classdef ESD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ESDMMUIFigure                 matlab.ui.Figure
        Menu                          matlab.ui.container.Menu
        AbbreviationsMenu             matlab.ui.container.Menu
        HelpveryimportantMenu         matlab.ui.container.Menu
        ContactusMenu                 matlab.ui.container.Menu
        DataPanel                     matlab.ui.container.Panel
        TimeperiodsPanel              matlab.ui.container.Panel
        TiInitialPanel                matlab.ui.container.Panel
        PLi                           matlab.ui.control.CheckBox
        LGMi                          matlab.ui.control.CheckBox
        MHi                           matlab.ui.control.CheckBox
        PIi                           matlab.ui.control.CheckBox
        TnFinalPanel                  matlab.ui.container.Panel
        LGMn                          matlab.ui.control.CheckBox
        MHn                           matlab.ui.control.CheckBox
        PIn                           matlab.ui.control.CheckBox
        PDn                           matlab.ui.control.CheckBox
        CatchmentparametersPanel      matlab.ui.container.Panel
        LRiverlengthkmPanel           matlab.ui.container.Panel
        L10                           matlab.ui.control.CheckBox
        L40                           matlab.ui.control.CheckBox
        L80                           matlab.ui.control.CheckBox
        UiInitialrockupliftmmyrPanel  matlab.ui.container.Panel
        Ui01                          matlab.ui.control.CheckBox
        Ui001                         matlab.ui.control.CheckBox
        Ui1                           matlab.ui.control.CheckBox
        Ui05                          matlab.ui.control.CheckBox
        KErodibilitymyr05Panel        matlab.ui.container.Panel
        K55                           matlab.ui.control.CheckBox
        K16                           matlab.ui.control.CheckBox
        K17                           matlab.ui.control.CheckBox
        DataLabel                     matlab.ui.control.Label
        N_map                         matlab.ui.control.NumericEditField
        LampRM                        matlab.ui.control.Lamp
        LampGM                        matlab.ui.control.Lamp
        NumberoffiguresPanel          matlab.ui.container.Panel
        ErosionprofileLabel           matlab.ui.control.Label
        totalprofile                  matlab.ui.control.NumericEditField
        ErosionmapLabel               matlab.ui.control.Label
        totalmap                      matlab.ui.control.NumericEditField
        PrecipitationmapLabel         matlab.ui.control.Label
        totalprecipitation            matlab.ui.control.NumericEditField
        PlothistogramLabel            matlab.ui.control.Label
        totalhistogarm                matlab.ui.control.NumericEditField
        ErosionMapPanel               matlab.ui.container.Panel
        MapcategoriesPanel            matlab.ui.container.Panel
        RT                            matlab.ui.control.CheckBox
        RE                            matlab.ui.control.CheckBox
        RE_Ui                         matlab.ui.control.CheckBox
        RE_Peak                       matlab.ui.control.CheckBox
        CE_Peak                       matlab.ui.control.CheckBox
        CE_Ui                         matlab.ui.control.CheckBox
        KPT                           matlab.ui.control.CheckBox
        CE                            matlab.ui.control.CheckBox
        atPanel                       matlab.ui.container.Panel
        atTT                          matlab.ui.control.CheckBox
        atEnd                         matlab.ui.control.CheckBox
        atREUi50                      matlab.ui.control.CheckBox
        atREUi40                      matlab.ui.control.CheckBox
        atREUi30                      matlab.ui.control.CheckBox
        atREUi20                      matlab.ui.control.CheckBox
        atREUi10                      matlab.ui.control.CheckBox
        atREPeak10                    matlab.ui.control.CheckBox
        atREPeak20                    matlab.ui.control.CheckBox
        atREPeak30                    matlab.ui.control.CheckBox
        atREPeak40                    matlab.ui.control.CheckBox
        atREPeak50                    matlab.ui.control.CheckBox
        atPeakRE                      matlab.ui.control.CheckBox
        atCEUi10                      matlab.ui.control.CheckBox
        atCEUi20                      matlab.ui.control.CheckBox
        atCEUi30                      matlab.ui.control.CheckBox
        atCEUi40                      matlab.ui.control.CheckBox
        atCEUi50                      matlab.ui.control.CheckBox
        atPeakCE                      matlab.ui.control.CheckBox
        atCEPeak10                    matlab.ui.control.CheckBox
        atCEPeak20                    matlab.ui.control.CheckBox
        atCEPeak30                    matlab.ui.control.CheckBox
        atCEPeak40                    matlab.ui.control.CheckBox
        atCEPeak50                    matlab.ui.control.CheckBox
        Bpeak                         matlab.ui.control.CheckBox
        Apeak                         matlab.ui.control.CheckBox
        OptionsPanel                  matlab.ui.container.Panel
        border                        matlab.ui.control.CheckBox
        stat                          matlab.ui.control.CheckBox
        sample                        matlab.ui.control.CheckBox
        ice                           matlab.ui.control.CheckBox
        PlotareaPanel                 matlab.ui.container.Panel
        TextArea                      matlab.ui.control.TextArea
        TextArea_2                    matlab.ui.control.TextArea
        LatEditFieldLabel             matlab.ui.control.Label
        LBlat                         matlab.ui.control.NumericEditField
        LonLabel                      matlab.ui.control.Label
        LBlong                        matlab.ui.control.NumericEditField
        LonLabel_2                    matlab.ui.control.Label
        TRlong                        matlab.ui.control.NumericEditField
        LatEditField_2Label           matlab.ui.control.Label
        TRlat                         matlab.ui.control.NumericEditField
        WorldCo                       matlab.ui.control.Button
        WorldCo_5                     matlab.ui.control.Button
        SouthAmericaCo_2              matlab.ui.control.Button
        WorldCo_3                     matlab.ui.control.Button
        SouthAmericaCo_3              matlab.ui.control.Button
        SouthAmericaCo                matlab.ui.control.Button
        WorldCo_4                     matlab.ui.control.Button
        WorldCo_6                     matlab.ui.control.Button
        SouthAmericaCo_4              matlab.ui.control.Button
        WorldCo_2                     matlab.ui.control.Button
        ColorbartypePanel             matlab.ui.container.Panel
        colorbar                      matlab.ui.control.Switch
        ErosionProfilePanel           matlab.ui.container.Panel
        SinglepointisNofEquatorEofPrimeMeridianPanel  matlab.ui.container.Panel
        Longitude180to180Label        matlab.ui.control.Label
        longinput                     matlab.ui.control.NumericEditField
        Latitude60to75Label           matlab.ui.control.Label
        latinput                      matlab.ui.control.NumericEditField
        Allovertheworld26119availablepointsPanel  matlab.ui.container.Panel
        NumberofrandompointsEditField_2Label  matlab.ui.control.Label
        numrandall                    matlab.ui.control.NumericEditField
        randall                       matlab.ui.control.CheckBox
        Cosmogenicsamplepoints3207samplepointsPanel  matlab.ui.container.Panel
        NumberofrandompointsEditField_3Label  matlab.ui.control.Label
        numrandsample                 matlab.ui.control.NumericEditField
        randsample                    matlab.ui.control.CheckBox
        BasedonStatisticthepeakoftherivererosionratePanel  matlab.ui.container.Panel
        maxpos                        matlab.ui.control.CheckBox
        medpos                        matlab.ui.control.CheckBox
        meanpos                       matlab.ui.control.CheckBox
        modepos                       matlab.ui.control.CheckBox
        minneg                        matlab.ui.control.CheckBox
        medov                         matlab.ui.control.CheckBox
        meanov                        matlab.ui.control.CheckBox
        modetot                       matlab.ui.control.CheckBox
        medneg                        matlab.ui.control.CheckBox
        meanneg                       matlab.ui.control.CheckBox
        modeneg                       matlab.ui.control.CheckBox
        Statistic                     matlab.ui.control.CheckBox
        coordinate                    matlab.ui.control.CheckBox
        PrecipitationMapPanel         matlab.ui.container.Panel
        PLprec                        matlab.ui.control.CheckBox
        LGMprec                       matlab.ui.control.CheckBox
        MHprec                        matlab.ui.control.CheckBox
        PIprec                        matlab.ui.control.CheckBox
        PDprec                        matlab.ui.control.CheckBox
        PLLGMdif                      matlab.ui.control.CheckBox
        PLMHdif                       matlab.ui.control.CheckBox
        PLPIdif                       matlab.ui.control.CheckBox
        PLPDdif                       matlab.ui.control.CheckBox
        LGMMHdif                      matlab.ui.control.CheckBox
        MHPIdif                       matlab.ui.control.CheckBox
        LGMPDdif                      matlab.ui.control.CheckBox
        LGMPIdif                      matlab.ui.control.CheckBox
        MHPDdif                       matlab.ui.control.CheckBox
        PIPDdif                       matlab.ui.control.CheckBox
        onefig                        matlab.ui.control.CheckBox
        sepfig                        matlab.ui.control.CheckBox
        RealtimemapselectedpointsandareaPanel  matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
        runpanel                      matlab.ui.container.Panel
        SavefolderButtonGroup         matlab.ui.container.ButtonGroup
        AlltogetherButton             matlab.ui.control.RadioButton
        CategorizedButton             matlab.ui.control.RadioButton
        JPG                           matlab.ui.control.CheckBox
        BMP                           matlab.ui.control.CheckBox
        PDF                           matlab.ui.control.CheckBox
        SVG                           matlab.ui.control.CheckBox
        PLOTCheckBox                  matlab.ui.control.CheckBox
        SAVECheckBox                  matlab.ui.control.CheckBox
        RUN                           matlab.ui.control.Button
        PlothistogramPanel            matlab.ui.container.Panel
        hist1                         matlab.ui.control.CheckBox
        hist2                         matlab.ui.control.CheckBox
    end

    properties (Access = public)
        MainPath % pwd
        BriefData % all the brief data
        PrecipitationDir
        PrecipitationData
        
        LatLongAlt % latitude longitude altitude
        
        SMPL
        A3
        ICE_LOC
        ICE_LOC_full
        COSM
        wplot
        scb %switch colorbar (fixed = 0 & adaptable = 1)
        cstlat
        cstlon
        brdrs % country borders
        
        allpnts % row of the all selected points for profile plot
        alltype % type of them
        allIPT % method
        
        % Check boxes
        vTPi                % initial time chekcbox value
        vTPn                % final time checkbox value
        vL                  % River length checkbox value
        vUi                 % Initial rock uplift checkbox value
        vK                  % bedrock detachment checkbox value
        vMT                 % map type checkbox value
        vatG                % @ General
        vatRE_Ui            % @ Change in RE(Ui)
        vatRE_Peak          % @ Change in RE(Peak)
        vatCE_BAP           % @ Change in CE Befor Peak & Change in CE After Peak
        vatCE_Ui            % @ Change in CE(Ui)
        vatCE_Peak          % @ Change in CE(Peak)
        vExt                % Options panel
        vat_all             % [vatG, vatRE_Ui, vatRE_Peak, vatCE_Ui, vatCE_Peak]             
        vPP                 % change in how to pick points in profiles
        vststPr             % pick value based on statistic
        vsmplrnd            % number of random points from the samples
        vallrnd             % number of random points from all points
        vcord               % lat & lon
        precHOW             % plot precipitation maps in one figure & plot precipitation maps in seperated figures
        prec                % Precipitation time period
        difprec             % Preciitation difference time period
        pltHIST             % plot histogram
        
        RP                  % Run panel
        
        InputData           % selected data based on user input from data panel
        
        plt_type 
        plt_at
        plt_xt
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %----------------------------- Fix the path
            if ismac && isdeployed
                app.MainPath = '/Applications/ESD_Tuebingen/application';
                MP = app.MainPath;
                cd(MP)
            else
                app.MainPath = pwd;
                MP = app.MainPath;
            end
            P1 = genpath('PrecipitationData');
            P2 = genpath('BriefData');
            P3 = genpath('ExtraData');
            addpath(P1,P2,P3);
            %----------------------------- % load the country borders data and plot them on realtime map
            load('Country_Border.mat') 
            app.brdrs = Borders;
            rectangle(app.UIAxes,'Position',[-180 -90 360 180],'FaceColor',[0 0 0 0.7],'EdgeColor','none');
            hold(app.UIAxes,'on');
            rectangle(app.UIAxes,'Position',[-180 -60 360 135],'FaceColor',[0 0 1 0.6],'EdgeColor','none');
            plot(app.UIAxes,Borders(2,:),Borders(1,:),'k','Linewidth',0.1);
            %----------------------------- % welcome text (just at program startup)
            tmp = "Welcome to ESDMM\n----------\nPlease read"+...
                  " HELP (Ctrl+H) & ABBREVIATIONS (Ctrl+I)"+...
                  " in the Extra tab of the Menu bar befoe using the program\n"+...
                  "----------\nIn case of any question or problem please contact us (Ctrl+C)\n----------\nEnJoY! :)"+...
                  "\n\nCurrentFolder:\n";
            wlcmTXT = sprintf(tmp+"%s",MP);
            %---------------------------------------------Creat a waitbar (initial data loading)
            uiwait(msgbox(char(wlcmTXT),'Welcome','modal'));
            wb = waitbar(0,'Preparing Initial Data...','Name','Loading Initial Data');
            pause(.5)
            %--------------------------------------------- step 1
            perc = 0/6;
            txt = "Loading a brief version of erosion data... " + string(round(perc*100,1)) + " %";
            waitbar(perc,wb,txt);
            pause(.5)
            cd('BriefData') % go to brief data folder
            app.BriefData = dir('*BriefOutput*.mat'); % extract the apth of all the brief data and store it in the "app"
            cd(MP); % go back to the main path
            %--------------------------------------------- step 2
            perc = 1/6;
            txt = "Loading Precipitation Data... " + string(round(perc*100,1)) + " %";
            waitbar(perc,wb,txt);
            pause(.5)
            cd('PrecipitationData') % go to precipitation data folder
            PrcDta = dir('*oceanmasked*.txt'); % extract the path of the precipitation data
            for i = 1:size(PrcDta,1) % load the oceanmasked precipitation data from the path
                tmp_data = load(PrcDta(i).name);
                % data = [lat      lon        p_PL        p_LGM       p_MH      p_PI      p_PD]
                data(:,1:2) = tmp_data(:,1:2); 
                data(:,i+2) = tmp_data(:,3);   
            end
            app.PrecipitationDir = PrcDta; % store the path of the oceanmasked precipitation data in the "app"
            app.PrecipitationData = data;  % store the oceanmasked precipitation data in the "app"
            cd(MP); % go back to the main path
            %--------------------------------------------- step 3
            perc = 2/6;
            txt = "Loading Lat,Long,Alt... " + string(round(perc*100,1)) + " %";
            waitbar(perc,wb,txt);
            pause(.5)
            cd('ExtraData') % go to the extradata folder
            LLA = load('LatLongAlt.mat'); % load the lat,lon,alt of all the data points
            lat = LLA.LatLongAlt(:,1); % get the latitude
            long= LLA.LatLongAlt(:,2); % get the longitude
            app.LatLongAlt = LLA.LatLongAlt; % store the lat,lon,alt in the "app"
            %--------------------------------------------- step 4
            perc = 3/6;
            txt = "Loading Samples Data... " + string(round(perc*100,1)) + " %";
            waitbar(perc,wb,txt);
            pause(.5)
            % load the lat,lon and data of the cosmo sample points
            smpl(:,1) = xlsread('OCTOPUSCRN_resp_times_v180623.xlsx',1,'C2:C3208'); % latitude
            smpl(:,2) = xlsread('OCTOPUSCRN_resp_times_v180623.xlsx',1,'D2:D3208'); % longitude
            smpl(:,3) = xlsread('OCTOPUSCRN_resp_times_v180623.xlsx',1,'L2:L3208'); % resp_time_soil_kyr
            % find the closest locatopn to the sample and pick the
            % calculated value to compare with the sample
            for i = 1:size(smpl,1)
                lat_usr = smpl(i,1);
                long_usr = smpl(i,2);
                A(:,1) = abs(lat_usr - lat);
                A(:,2) = abs(long_usr - long);
                A(:,3) = sqrt((A(:,1).^2) + (A(:,2).^2));
                [~,p] = min(A(:,3));
                smpl(i,4) = p;
            end
            a3 = smpl(:,4);
            smpl(:,5) = lat(a3);
            smpl(:,6) = long(a3);
            app.SMPL = smpl;
            app.A3 = a3;
            %--------------------------------------------- step 5
            perc = 4/6;
            txt = "Loading LGM Ice Cover Data... " + string(round(perc*100,1)) + " %";
            waitbar(perc,wb,txt);
            pause(.5)
            GLAC_lat = ncread('T159_jan_surf_lgm_veg.nc','lat');
            GLAC_lon = ncread('T159_jan_surf_lgm_veg.nc','lon');
            GLAC = ncread('T159_jan_surf_lgm_veg.nc','GLAC');
            GLAC = GLAC';
            [msh_lon,msh_lat] = meshgrid (GLAC_lon,GLAC_lat);
            A=find(GLAC==1);    
            GLT = msh_lat(A);
            GLN = msh_lon(A);
            ice_loc = [GLT,GLN];
            ic_tmp1 = find(ice_loc(:,2) > 180);
            ic_tmp2 = ice_loc;
            ic_tmp2(ic_tmp1,2) = ic_tmp2(ic_tmp1,2) - 360;
            app.ICE_LOC_full = ic_tmp2;
            A = find(ice_loc(:,1)>75 | ice_loc(:,1)<-60);
            ice_loc(A,:) = [];
            app.ICE_LOC = ice_loc;
            %--------------------------------------------- step 6
            perc = 5/6;
            txt = "Loading Precipitation Data... " + string(round(perc*100,1)) + " %";
            waitbar(perc,wb,txt);
            pause(.5)
            cosmovar = load("LatLongAltSF123.txt");
            app.COSM = cosmovar;
            %---------------------------------------------
            perc = 6/6;
            txt = "All Done... " + string(round(perc*100,1)) + " %";
            waitbar(perc,wb,txt);
            pause(.5)
            close(wb);
            cd(MP);
            uiwait(msgbox('READY TO USE','READY','modal'));
        end

        % Value changed function: K16, K17, K55, L10, L40, L80, LGMi, 
        % LGMn, MHi, MHn, PDn, PIi, PIn, PLi, Ui001, Ui01, Ui05, Ui1
        function SelectDataParameters(app, event)
% Data selection checkboxes
            %--------------Check all the check boxes and options to update them
            app_FUNC_GeneralCheckBox(app);
            %--------------update real-time map and pick selected points (only for profile)
            app_FUNC_realtimemap(app)
        end

        % Value changed function: CE, CE_Peak, CE_Ui, KPT, RE, 
        % RE_Peak, RE_Ui, RT
        function MapCategoryParameters(app, event)
% map type checkboxes
            %--------------Check all the check boxes and options to update them
            app_FUNC_GeneralCheckBox(app);
            %--------------update real-time map and pick selected points (only for profile)
            app_FUNC_realtimemap(app)
        end

        % Value changed function: Apeak, Bpeak, atCEPeak10, 
        % atCEPeak20, atCEPeak30, atCEPeak40, atCEPeak50, atCEUi10, 
        % atCEUi20, atCEUi30, atCEUi40, atCEUi50, atEnd, atPeakCE, 
        % atPeakRE, atREPeak10, atREPeak20, atREPeak30, atREPeak40, 
        % atREPeak50, atREUi10, atREUi20, atREUi30, atREUi40, 
        % atREUi50, atTT
        function MapAtParameters(app, event)
% @ checkboxes
            %--------------Check all the check boxes and options to update them
            app_FUNC_GeneralCheckBox(app);
            %--------------update real-time map and pick selected points (only for profile)
            app_FUNC_realtimemap(app)
        end

        % Value changed function: LBlat, LBlong, TRlat, TRlong, 
        % border, colorbar, ice, sample, stat
        function MapExtraParameters(app, event)
% Extra and Save checkboxes
            %--------------Check all the check boxes and options to update them
            app_FUNC_GeneralCheckBox(app);
            %--------------update real-time map and pick selected points (only for profile)
            app_FUNC_realtimemap(app)
        end

        % Value changed function: Statistic, coordinate, latinput, 
        % longinput, maxpos, meanneg, meanov, meanpos, medneg, medov, 
        % medpos, minneg, modeneg, modepos, modetot, numrandall, 
        % numrandsample, randall, randsample
        function ProfileParameters(app, event)
            %--------------Check all the check boxes and options to update them
            app_FUNC_GeneralCheckBox(app);
            %--------------update real-time map and pick selected points (only for profile)
            app_FUNC_realtimemap(app)
        end

        % Value changed function: LGMMHdif, LGMPDdif, LGMPIdif, 
        % LGMprec, MHPDdif, MHPIdif, MHprec, PDprec, PIPDdif, PIprec, 
        % PLLGMdif, PLMHdif, PLPDdif, PLPIdif, PLprec, onefig, sepfig
        function PrecipitationParameters(app, event)
            %--------------Check all the check boxes and options to update them
            app_FUNC_GeneralCheckBox(app);
            %--------------update real-time map and pick selected points (only for profile)
            app_FUNC_realtimemap(app)
        end

        % Menu selected function: HelpveryimportantMenu
        function HELP(app, event)
            if ispc
                cd('ExtraData')
                winopen('HELP.pdf')
            elseif ismac
                cd('/Applications/UniTuebingenESD/ESDMM/application/ExtraData')
                macopen('HELP.pdf')
            end
            cd(app.MainPath)
        end

        % Menu selected function: ContactusMenu
        function ABOUT(app, event)
            aboutTXT = sprintf("Todd.Ehlers@uni-tuebingen.de\n\nMohammadreza.Ershadi@uni-tuebingen.de");
            msgbox(aboutTXT);
        end

        % Menu selected function: AbbreviationsMenu
        function AbbreviationsMenuSelected(app, event)
            tmp = "----------\nTime periods:\nPL: Pliocene\nLGM: Last-Glacial-Maximum\nMH: Mid-Holocene\nPI: Pre-Industrial\nPD: Presend-Day\n"+...
                  "----------\nMap categories:\nRT: Response Time\nRE: mean catchment River Erosion rate\nRE(Ui): change in RE relative to Ui\n"+...
                  "RE(Peak): change in RE relative to Peak\nCE: mean catchment Cosmogenic Erosion rate\n"+...
                  "CE(Ui): change in CE relative to Ui\nCE(Peak): change in CE relative to Peak\n"+...
                  "KPT: lateral shift in Knickpoint position";
            abbrTXT = sprintf(tmp);
            helpdlg(abbrTXT,"Abbreviations");
        end

        % Button pushed function: WorldCo
        function WorldCoButtonPushed(app, event)
            app.LBlat.Value = -60;
            app.LBlong.Value = -180;
            app.TRlat.Value = 75;
            app.TRlong.Value = 180;
            %--------------Check all the check boxes and options to update them
            app_FUNC_GeneralCheckBox(app);
            %--------------update real-time map and pick selected points (only for profile)
            app_FUNC_realtimemap(app)
        end

        % Button pushed function: SouthAmericaCo
        function SouthAmericaCoButtonPushed(app, event)
            app.LBlat.Value = -58;
            app.LBlong.Value = -83;
            app.TRlat.Value = 14;
            app.TRlong.Value = -33;
            %--------------Check all the check boxes and options to update them
            app_FUNC_GeneralCheckBox(app);
            %--------------update real-time map and pick selected points (only for profile)
            app_FUNC_realtimemap(app)
        end

        % Button pushed function: WorldCo_2
        function WorldCo_2ButtonPushed(app, event)
            app.LBlat.Value = 47;
            app.LBlong.Value = 5.5;
            app.TRlat.Value = 55.5;
            app.TRlong.Value = 15.5;
            %--------------Check all the check boxes and options to update them
            app_FUNC_GeneralCheckBox(app);
            %--------------update real-time map and pick selected points (only for profile)
            app_FUNC_realtimemap(app)
        end

        % Callback function: BMP, JPG, PDF, PLOTCheckBox, 
        % SAVECheckBox, SVG, SavefolderButtonGroup
        function RUNpanel(app, event)
            %--------------Check all the check boxes and options to update them
            app_FUNC_GeneralCheckBox(app);
            %--------------update real-time map and pick selected points (only for profile)
%             app_FUNC_realtimemap(app)
        end

        % Button pushed function: RUN
        function RUNButtonPushed(app, event)
            %--------------Check all the check boxes and options to update them
            app_FUNC_GeneralCheckBox(app);
            %--------------update real-time map and pick selected points (only for profile)
%             app_FUNC_realtimemap(app)
            %--------------
            app_FUNC_pushbut(app);
        end

        % Button pushed function: WorldCo_3
        function WorldCo_3ButtonPushed(app, event)
            app.LBlat.Value = -46;
            app.LBlong.Value = 110;
            app.TRlat.Value = -9;
            app.TRlong.Value = 156;
            %--------------Check all the check boxes and options to update them
            app_FUNC_GeneralCheckBox(app);
            %--------------update real-time map and pick selected points (only for profile)
            app_FUNC_realtimemap(app)
        end

        % Button pushed function: WorldCo_4
        function WorldCo_4ButtonPushed(app, event)
            app.LBlat.Value = -41;
            app.LBlong.Value = -20;
            app.TRlat.Value = 42;
            app.TRlong.Value = 58;
            %--------------Check all the check boxes and options to update them
            app_FUNC_GeneralCheckBox(app);
            %--------------update real-time map and pick selected points (only for profile)
            app_FUNC_realtimemap(app)
        end

        % Button pushed function: SouthAmericaCo_2
        function SouthAmericaCo_2ButtonPushed(app, event)
            app.LBlat.Value = 10;
            app.LBlong.Value = -168;
            app.TRlat.Value = 75;
            app.TRlong.Value = -20;
            %--------------Check all the check boxes and options to update them
            app_FUNC_GeneralCheckBox(app);
            %--------------update real-time map and pick selected points (only for profile)
            app_FUNC_realtimemap(app)
        end

        % Button pushed function: SouthAmericaCo_3
        function SouthAmericaCo_3ButtonPushed(app, event)
            app.LBlat.Value = 35;
            app.LBlong.Value = -25;
            app.TRlat.Value = 75;
            app.TRlong.Value = 50;
            %--------------Check all the check boxes and options to update them
            app_FUNC_GeneralCheckBox(app);
            %--------------update real-time map and pick selected points (only for profile)
            app_FUNC_realtimemap(app)
        end

        % Button pushed function: SouthAmericaCo_4
        function SouthAmericaCo_4ButtonPushed(app, event)
            app.LBlat.Value = -13;
            app.LBlong.Value = 40;
            app.TRlat.Value = 75;
            app.TRlong.Value = 180;
            %--------------Check all the check boxes and options to update them
            app_FUNC_GeneralCheckBox(app);
            %--------------update real-time map and pick selected points (only for profile)
            app_FUNC_realtimemap(app)
        end

        % Button pushed function: WorldCo_5
        function WorldCo_5ButtonPushed(app, event)
            app.LBlat.Value = -45;
            app.LBlong.Value = -80;
            app.TRlat.Value = -10;
            app.TRlong.Value = -60;
            %--------------Check all the check boxes and options to update them
            app_FUNC_GeneralCheckBox(app);
            %--------------update real-time map and pick selected points (only for profile)
            app_FUNC_realtimemap(app)
        end

        % Button pushed function: WorldCo_6
        function WorldCo_6ButtonPushed(app, event)
            app.LBlat.Value = 26;
            app.LBlong.Value = -130;
            app.TRlat.Value = 52;
            app.TRlong.Value = -50;
            %--------------Check all the check boxes and options to update them
            app_FUNC_GeneralCheckBox(app);
            %--------------update real-time map and pick selected points (only for profile)
            app_FUNC_realtimemap(app)
        end

        % Value changed function: hist1, hist2
        function Histogram_panel(app, event)
            %--------------Check all the check boxes and options to update them
            app_FUNC_GeneralCheckBox(app);
            %--------------update real-time map and pick selected points (only for profile)
            app_FUNC_realtimemap(app)
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ESDMMUIFigure = uifigure;
            app.ESDMMUIFigure.Name = 'ESD.M.M';
            app.Menu = uimenu(app.ESDMMUIFigure);
            app.Menu.Text = 'Menu';
            app.AbbreviationsMenu = uimenu(app.Menu);
            app.AbbreviationsMenu.MenuSelectedFcn = createCallbackFcn(app, @AbbreviationsMenuSelected, true);
            app.AbbreviationsMenu.Accelerator = 'A';
            app.AbbreviationsMenu.Text = 'Abbreviations';
            app.HelpveryimportantMenu = uimenu(app.Menu);
            app.HelpveryimportantMenu.MenuSelectedFcn = createCallbackFcn(app, @HELP, true);
            app.HelpveryimportantMenu.Accelerator = 'H';
            app.HelpveryimportantMenu.Text = 'Help (very important)';
            app.ContactusMenu = uimenu(app.Menu);
            app.ContactusMenu.MenuSelectedFcn = createCallbackFcn(app, @ABOUT, true);
            app.ContactusMenu.Accelerator = 'c';
            app.ContactusMenu.Text = 'Contact us';
            app.DataPanel = uipanel(app.ESDMMUIFigure);
            app.DataPanel.Title = 'Data';
            app.TimeperiodsPanel = uipanel(app.DataPanel);
            app.TimeperiodsPanel.Title = 'Time periods';
            app.TiInitialPanel = uipanel(app.TimeperiodsPanel);
            app.TiInitialPanel.Title = 'Ti: Initial';
            app.PLi = uicheckbox(app.TiInitialPanel);
            app.PLi.ValueChangedFcn = createCallbackFcn(app, @SelectDataParameters, true);
            app.PLi.Text = 'PL';
            app.LGMi = uicheckbox(app.TiInitialPanel);
            app.LGMi.ValueChangedFcn = createCallbackFcn(app, @SelectDataParameters, true);
            app.LGMi.Text = 'LGM';
            app.MHi = uicheckbox(app.TiInitialPanel);
            app.MHi.ValueChangedFcn = createCallbackFcn(app, @SelectDataParameters, true);
            app.MHi.Text = 'MH';
            app.PIi = uicheckbox(app.TiInitialPanel);
            app.PIi.ValueChangedFcn = createCallbackFcn(app, @SelectDataParameters, true);
            app.PIi.Text = 'PI';
            app.TnFinalPanel = uipanel(app.TimeperiodsPanel);
            app.TnFinalPanel.Title = 'Tn: Final';
            app.LGMn = uicheckbox(app.TnFinalPanel);
            app.LGMn.ValueChangedFcn = createCallbackFcn(app, @SelectDataParameters, true);
            app.LGMn.Text = 'LGM';
            app.MHn = uicheckbox(app.TnFinalPanel);
            app.MHn.ValueChangedFcn = createCallbackFcn(app, @SelectDataParameters, true);
            app.MHn.Text = 'MH';
            app.PIn = uicheckbox(app.TnFinalPanel);
            app.PIn.ValueChangedFcn = createCallbackFcn(app, @SelectDataParameters, true);
            app.PIn.Text = 'PI';
            app.PDn = uicheckbox(app.TnFinalPanel);
            app.PDn.ValueChangedFcn = createCallbackFcn(app, @SelectDataParameters, true);
            app.PDn.Text = 'PD';
            app.CatchmentparametersPanel = uipanel(app.DataPanel);
            app.CatchmentparametersPanel.Title = 'Catchment parameters';
            app.LRiverlengthkmPanel = uipanel(app.CatchmentparametersPanel);
            app.LRiverlengthkmPanel.Title = 'L: River length [km]';
            app.L10 = uicheckbox(app.LRiverlengthkmPanel);
            app.L10.ValueChangedFcn = createCallbackFcn(app, @SelectDataParameters, true);
            app.L10.Text = '10';
            app.L40 = uicheckbox(app.LRiverlengthkmPanel);
            app.L40.ValueChangedFcn = createCallbackFcn(app, @SelectDataParameters, true);
            app.L40.Text = '40';
            app.L80 = uicheckbox(app.LRiverlengthkmPanel);
            app.L80.ValueChangedFcn = createCallbackFcn(app, @SelectDataParameters, true);
            app.L80.Text = '80';
            app.UiInitialrockupliftmmyrPanel = uipanel(app.CatchmentparametersPanel);
            app.UiInitialrockupliftmmyrPanel.Title = 'Ui: Initial rock uplift [mm/yr]';
            app.Ui01 = uicheckbox(app.UiInitialrockupliftmmyrPanel);
            app.Ui01.ValueChangedFcn = createCallbackFcn(app, @SelectDataParameters, true);
            app.Ui01.Text = '0.1';
            app.Ui001 = uicheckbox(app.UiInitialrockupliftmmyrPanel);
            app.Ui001.ValueChangedFcn = createCallbackFcn(app, @SelectDataParameters, true);
            app.Ui001.Text = '0.01';
            app.Ui1 = uicheckbox(app.UiInitialrockupliftmmyrPanel);
            app.Ui1.ValueChangedFcn = createCallbackFcn(app, @SelectDataParameters, true);
            app.Ui1.Text = '1';
            app.Ui05 = uicheckbox(app.UiInitialrockupliftmmyrPanel);
            app.Ui05.ValueChangedFcn = createCallbackFcn(app, @SelectDataParameters, true);
            app.Ui05.Text = '0.5';
            app.KErodibilitymyr05Panel = uipanel(app.CatchmentparametersPanel);
            app.KErodibilitymyr05Panel.Title = 'K: Erodibility [(m.yr)^(-0.5)]';
            app.K55 = uicheckbox(app.KErodibilitymyr05Panel);
            app.K55.ValueChangedFcn = createCallbackFcn(app, @SelectDataParameters, true);
            app.K55.Text = '5e-5';
            app.K16 = uicheckbox(app.KErodibilitymyr05Panel);
            app.K16.ValueChangedFcn = createCallbackFcn(app, @SelectDataParameters, true);
            app.K16.Text = '1e-6';
            app.K17 = uicheckbox(app.KErodibilitymyr05Panel);
            app.K17.ValueChangedFcn = createCallbackFcn(app, @SelectDataParameters, true);
            app.K17.Text = '1e-7';
            app.DataLabel = uilabel(app.DataPanel);
            app.DataLabel.Text = 'Data';
            app.N_map = uieditfield(app.DataPanel, 'numeric');
            app.N_map.ValueDisplayFormat = '%.0f';
            app.N_map.Editable = 'off';
            app.LampRM = uilamp(app.DataPanel);
            app.LampGM = uilamp(app.DataPanel);
            app.LampGM.Enable = 'off';
            app.NumberoffiguresPanel = uipanel(app.ESDMMUIFigure);
            app.NumberoffiguresPanel.Title = '** Number of figures **';
            app.ErosionprofileLabel = uilabel(app.NumberoffiguresPanel);
            app.ErosionprofileLabel.Text = 'Erosion profile';
            app.totalprofile = uieditfield(app.NumberoffiguresPanel, 'numeric');
            app.totalprofile.ValueDisplayFormat = '%.0f';
            app.totalprofile.Editable = 'off';
            app.ErosionmapLabel = uilabel(app.NumberoffiguresPanel);
            app.ErosionmapLabel.Text = 'Erosion map';
            app.totalmap = uieditfield(app.NumberoffiguresPanel, 'numeric');
            app.totalmap.ValueDisplayFormat = '%.0f';
            app.totalmap.Editable = 'off';
            app.PrecipitationmapLabel = uilabel(app.NumberoffiguresPanel);
            app.PrecipitationmapLabel.Text = 'Precipitation map';
            app.totalprecipitation = uieditfield(app.NumberoffiguresPanel, 'numeric');
            app.totalprecipitation.ValueDisplayFormat = '%.0f';
            app.totalprecipitation.Editable = 'off';
            app.PlothistogramLabel = uilabel(app.NumberoffiguresPanel);
            app.PlothistogramLabel.Text = 'Plot histogram';
            app.totalhistogarm = uieditfield(app.NumberoffiguresPanel, 'numeric');
            app.totalhistogarm.ValueDisplayFormat = '%.0f';
            app.totalhistogarm.Editable = 'off';
            app.ErosionMapPanel = uipanel(app.ESDMMUIFigure);
            app.ErosionMapPanel.Title = 'Erosion Map';
            app.MapcategoriesPanel = uipanel(app.ErosionMapPanel);
            app.MapcategoriesPanel.Title = 'Map categories';
            app.RT = uicheckbox(app.MapcategoriesPanel);
            app.RT.ValueChangedFcn = createCallbackFcn(app, @MapCategoryParameters, true);
            app.RT.Enable = 'off';
            app.RT.Text = 'RT: Response time [kyr]';
            app.RE = uicheckbox(app.MapcategoriesPanel);
            app.RE.ValueChangedFcn = createCallbackFcn(app, @MapCategoryParameters, true);
            app.RE.Enable = 'off';
            app.RE.Text = 'RE: Mean catchment River Erosion rate [mm/yr]';
            app.RE_Ui = uicheckbox(app.MapcategoriesPanel);
            app.RE_Ui.ValueChangedFcn = createCallbackFcn(app, @MapCategoryParameters, true);
            app.RE_Ui.Enable = 'off';
            app.RE_Ui.Text = 'RE(Ui): Change in RE relative to Ui [%]';
            app.RE_Peak = uicheckbox(app.MapcategoriesPanel);
            app.RE_Peak.ValueChangedFcn = createCallbackFcn(app, @MapCategoryParameters, true);
            app.RE_Peak.Enable = 'off';
            app.RE_Peak.Text = 'RE(Peak): Change in RE relative to Peak [%]';
            app.CE_Peak = uicheckbox(app.MapcategoriesPanel);
            app.CE_Peak.ValueChangedFcn = createCallbackFcn(app, @MapCategoryParameters, true);
            app.CE_Peak.Enable = 'off';
            app.CE_Peak.Text = 'CE(Peak): Change in CE relative to Peak [%]';
            app.CE_Ui = uicheckbox(app.MapcategoriesPanel);
            app.CE_Ui.ValueChangedFcn = createCallbackFcn(app, @MapCategoryParameters, true);
            app.CE_Ui.Enable = 'off';
            app.CE_Ui.Text = 'CE(Ui): Change in CE relative to Ui [%]';
            app.KPT = uicheckbox(app.MapcategoriesPanel);
            app.KPT.ValueChangedFcn = createCallbackFcn(app, @MapCategoryParameters, true);
            app.KPT.Enable = 'off';
            app.KPT.Text = 'KPT: Lateral shift in knickpoint position [%]';
            app.CE = uicheckbox(app.MapcategoriesPanel);
            app.CE.ValueChangedFcn = createCallbackFcn(app, @MapCategoryParameters, true);
            app.CE.Enable = 'off';
            app.CE.Text = 'CE: Mean catchment Cosmogenic Erosion rate [mm/yr]';
            app.atPanel = uipanel(app.ErosionMapPanel);
            app.atPanel.Title = '@ What point ???';
            app.atTT = uicheckbox(app.atPanel);
            app.atTT.ValueChangedFcn = createCallbackFcn(app, @MapAtParameters, true);
            app.atTT.Enable = 'off';
            app.atTT.Text = '@Tn';
            app.atEnd = uicheckbox(app.atPanel);
            app.atEnd.ValueChangedFcn = createCallbackFcn(app, @MapAtParameters, true);
            app.atEnd.Enable = 'off';
            app.atEnd.Text = '@N.S.S.';
            app.atREUi50 = uicheckbox(app.atPanel);
            app.atREUi50.ValueChangedFcn = createCallbackFcn(app, @MapAtParameters, true);
            app.atREUi50.Enable = 'off';
            app.atREUi50.Text = '@50% RE(Ui)';
            app.atREUi40 = uicheckbox(app.atPanel);
            app.atREUi40.ValueChangedFcn = createCallbackFcn(app, @MapAtParameters, true);
            app.atREUi40.Enable = 'off';
            app.atREUi40.Text = '@40% RE(Ui)';
            app.atREUi30 = uicheckbox(app.atPanel);
            app.atREUi30.ValueChangedFcn = createCallbackFcn(app, @MapAtParameters, true);
            app.atREUi30.Enable = 'off';
            app.atREUi30.Text = '@30% RE(Ui)';
            app.atREUi20 = uicheckbox(app.atPanel);
            app.atREUi20.ValueChangedFcn = createCallbackFcn(app, @MapAtParameters, true);
            app.atREUi20.Enable = 'off';
            app.atREUi20.Text = '@20% RE(Ui)';
            app.atREUi10 = uicheckbox(app.atPanel);
            app.atREUi10.ValueChangedFcn = createCallbackFcn(app, @MapAtParameters, true);
            app.atREUi10.Enable = 'off';
            app.atREUi10.Text = '@10% RE(Ui)';
            app.atREPeak10 = uicheckbox(app.atPanel);
            app.atREPeak10.ValueChangedFcn = createCallbackFcn(app, @MapAtParameters, true);
            app.atREPeak10.Enable = 'off';
            app.atREPeak10.Text = '@10% RE(Peak)';
            app.atREPeak20 = uicheckbox(app.atPanel);
            app.atREPeak20.ValueChangedFcn = createCallbackFcn(app, @MapAtParameters, true);
            app.atREPeak20.Enable = 'off';
            app.atREPeak20.Text = '@20% RE(Peak)';
            app.atREPeak30 = uicheckbox(app.atPanel);
            app.atREPeak30.ValueChangedFcn = createCallbackFcn(app, @MapAtParameters, true);
            app.atREPeak30.Enable = 'off';
            app.atREPeak30.Text = '@30% RE(Peak)';
            app.atREPeak40 = uicheckbox(app.atPanel);
            app.atREPeak40.ValueChangedFcn = createCallbackFcn(app, @MapAtParameters, true);
            app.atREPeak40.Enable = 'off';
            app.atREPeak40.Text = '@40% RE(Peak)';
            app.atREPeak50 = uicheckbox(app.atPanel);
            app.atREPeak50.ValueChangedFcn = createCallbackFcn(app, @MapAtParameters, true);
            app.atREPeak50.Enable = 'off';
            app.atREPeak50.Text = '@50% RE(Peak)';
            app.atPeakRE = uicheckbox(app.atPanel);
            app.atPeakRE.ValueChangedFcn = createCallbackFcn(app, @MapAtParameters, true);
            app.atPeakRE.Enable = 'off';
            app.atPeakRE.Text = '@Peak RE';
            app.atCEUi10 = uicheckbox(app.atPanel);
            app.atCEUi10.ValueChangedFcn = createCallbackFcn(app, @MapAtParameters, true);
            app.atCEUi10.Enable = 'off';
            app.atCEUi10.Text = '@10% CE(Ui)';
            app.atCEUi20 = uicheckbox(app.atPanel);
            app.atCEUi20.ValueChangedFcn = createCallbackFcn(app, @MapAtParameters, true);
            app.atCEUi20.Enable = 'off';
            app.atCEUi20.Text = '@20% CE(Ui)';
            app.atCEUi30 = uicheckbox(app.atPanel);
            app.atCEUi30.ValueChangedFcn = createCallbackFcn(app, @MapAtParameters, true);
            app.atCEUi30.Enable = 'off';
            app.atCEUi30.Text = '@30% CE(Ui)';
            app.atCEUi40 = uicheckbox(app.atPanel);
            app.atCEUi40.ValueChangedFcn = createCallbackFcn(app, @MapAtParameters, true);
            app.atCEUi40.Enable = 'off';
            app.atCEUi40.Text = '@40% CE(Ui)';
            app.atCEUi50 = uicheckbox(app.atPanel);
            app.atCEUi50.ValueChangedFcn = createCallbackFcn(app, @MapAtParameters, true);
            app.atCEUi50.Enable = 'off';
            app.atCEUi50.Text = '@50% CE(Ui)';
            app.atPeakCE = uicheckbox(app.atPanel);
            app.atPeakCE.ValueChangedFcn = createCallbackFcn(app, @MapAtParameters, true);
            app.atPeakCE.Enable = 'off';
            app.atPeakCE.Text = '@Peak CE';
            app.atCEPeak10 = uicheckbox(app.atPanel);
            app.atCEPeak10.ValueChangedFcn = createCallbackFcn(app, @MapAtParameters, true);
            app.atCEPeak10.Enable = 'off';
            app.atCEPeak10.Text = '@10% CE(Peak)';
            app.atCEPeak20 = uicheckbox(app.atPanel);
            app.atCEPeak20.ValueChangedFcn = createCallbackFcn(app, @MapAtParameters, true);
            app.atCEPeak20.Enable = 'off';
            app.atCEPeak20.Text = '@20% CE(Peak)';
            app.atCEPeak30 = uicheckbox(app.atPanel);
            app.atCEPeak30.ValueChangedFcn = createCallbackFcn(app, @MapAtParameters, true);
            app.atCEPeak30.Enable = 'off';
            app.atCEPeak30.Text = '@30% CE(Peak)';
            app.atCEPeak40 = uicheckbox(app.atPanel);
            app.atCEPeak40.ValueChangedFcn = createCallbackFcn(app, @MapAtParameters, true);
            app.atCEPeak40.Enable = 'off';
            app.atCEPeak40.Text = '@40% CE(Peak)';
            app.atCEPeak50 = uicheckbox(app.atPanel);
            app.atCEPeak50.ValueChangedFcn = createCallbackFcn(app, @MapAtParameters, true);
            app.atCEPeak50.Enable = 'off';
            app.atCEPeak50.Text = '@50% CE(Peak)';
            app.Bpeak = uicheckbox(app.atPanel);
            app.Bpeak.ValueChangedFcn = createCallbackFcn(app, @MapAtParameters, true);
            app.Bpeak.Enable = 'off';
            app.Bpeak.Text = 'Before Peak';
            app.Apeak = uicheckbox(app.atPanel);
            app.Apeak.ValueChangedFcn = createCallbackFcn(app, @MapAtParameters, true);
            app.Apeak.Enable = 'off';
            app.Apeak.Text = 'After Peak';
            app.OptionsPanel = uipanel(app.ESDMMUIFigure);
            app.OptionsPanel.Title = '** Options **';
            app.border = uicheckbox(app.OptionsPanel);
            app.border.ValueChangedFcn = createCallbackFcn(app, @MapExtraParameters, true);
            app.border.Text = 'Country borders';
            app.border.Value = true;
            app.stat = uicheckbox(app.OptionsPanel);
            app.stat.ValueChangedFcn = createCallbackFcn(app, @MapExtraParameters, true);
            app.stat.Text = 'Statistic values';
            app.stat.Value = true;
            app.sample = uicheckbox(app.OptionsPanel);
            app.sample.ValueChangedFcn = createCallbackFcn(app, @MapExtraParameters, true);
            app.sample.Text = 'Cosmo sample';
            app.ice = uicheckbox(app.OptionsPanel);
            app.ice.ValueChangedFcn = createCallbackFcn(app, @MapExtraParameters, true);
            app.ice.Text = 'LGM ice cover';
            app.PlotareaPanel = uipanel(app.OptionsPanel);
            app.PlotareaPanel.Title = 'Plot area';
            app.TextArea = uitextarea(app.PlotareaPanel);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Bottom left'};
            app.TextArea_2 = uitextarea(app.PlotareaPanel);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'Top right'};
            app.LatEditFieldLabel = uilabel(app.PlotareaPanel);
            app.LatEditFieldLabel.Text = 'Lat';
            app.LBlat = uieditfield(app.PlotareaPanel, 'numeric');
            app.LBlat.Limits = [-60 74.99];
            app.LBlat.ValueDisplayFormat = '%.2f';
            app.LBlat.ValueChangedFcn = createCallbackFcn(app, @MapExtraParameters, true);
            app.LBlat.Value = -60;
            app.LonLabel = uilabel(app.PlotareaPanel);
            app.LonLabel.Text = 'Lon';
            app.LBlong = uieditfield(app.PlotareaPanel, 'numeric');
            app.LBlong.Limits = [-180 179.99];
            app.LBlong.ValueDisplayFormat = '%.2f';
            app.LBlong.ValueChangedFcn = createCallbackFcn(app, @MapExtraParameters, true);
            app.LBlong.Value = -180;
            app.LonLabel_2 = uilabel(app.PlotareaPanel);
            app.LonLabel_2.Text = 'Lon';
            app.TRlong = uieditfield(app.PlotareaPanel, 'numeric');
            app.TRlong.Limits = [-179.99 180];
            app.TRlong.ValueDisplayFormat = '%.2f';
            app.TRlong.ValueChangedFcn = createCallbackFcn(app, @MapExtraParameters, true);
            app.TRlong.Value = 180;
            app.LatEditField_2Label = uilabel(app.PlotareaPanel);
            app.LatEditField_2Label.Text = 'Lat';
            app.TRlat = uieditfield(app.PlotareaPanel, 'numeric');
            app.TRlat.Limits = [-59.99 75];
            app.TRlat.ValueDisplayFormat = '%.2f';
            app.TRlat.ValueChangedFcn = createCallbackFcn(app, @MapExtraParameters, true);
            app.TRlat.Value = 75;
            app.WorldCo = uibutton(app.PlotareaPanel, 'push');
            app.WorldCo.ButtonPushedFcn = createCallbackFcn(app, @WorldCoButtonPushed, true);
            app.WorldCo.Text = 'World';
            app.WorldCo_5 = uibutton(app.PlotareaPanel, 'push');
            app.WorldCo_5.ButtonPushedFcn = createCallbackFcn(app, @WorldCo_5ButtonPushed, true);
            app.WorldCo_5.Text = 'EarthShape';
            app.SouthAmericaCo_2 = uibutton(app.PlotareaPanel, 'push');
            app.SouthAmericaCo_2.ButtonPushedFcn = createCallbackFcn(app, @SouthAmericaCo_2ButtonPushed, true);
            app.SouthAmericaCo_2.Text = 'North America';
            app.WorldCo_3 = uibutton(app.PlotareaPanel, 'push');
            app.WorldCo_3.ButtonPushedFcn = createCallbackFcn(app, @WorldCo_3ButtonPushed, true);
            app.WorldCo_3.Text = 'Australia';
            app.SouthAmericaCo_3 = uibutton(app.PlotareaPanel, 'push');
            app.SouthAmericaCo_3.ButtonPushedFcn = createCallbackFcn(app, @SouthAmericaCo_3ButtonPushed, true);
            app.SouthAmericaCo_3.Text = 'Europe';
            app.SouthAmericaCo = uibutton(app.PlotareaPanel, 'push');
            app.SouthAmericaCo.ButtonPushedFcn = createCallbackFcn(app, @SouthAmericaCoButtonPushed, true);
            app.SouthAmericaCo.Text = 'South America';
            app.WorldCo_4 = uibutton(app.PlotareaPanel, 'push');
            app.WorldCo_4.ButtonPushedFcn = createCallbackFcn(app, @WorldCo_4ButtonPushed, true);
            app.WorldCo_4.Text = 'Africa';
            app.WorldCo_6 = uibutton(app.PlotareaPanel, 'push');
            app.WorldCo_6.ButtonPushedFcn = createCallbackFcn(app, @WorldCo_6ButtonPushed, true);
            app.WorldCo_6.Text = 'USA';
            app.SouthAmericaCo_4 = uibutton(app.PlotareaPanel, 'push');
            app.SouthAmericaCo_4.ButtonPushedFcn = createCallbackFcn(app, @SouthAmericaCo_4ButtonPushed, true);
            app.SouthAmericaCo_4.Text = 'Asia';
            app.WorldCo_2 = uibutton(app.PlotareaPanel, 'push');
            app.WorldCo_2.ButtonPushedFcn = createCallbackFcn(app, @WorldCo_2ButtonPushed, true);
            app.WorldCo_2.Text = 'Germany';
            app.ColorbartypePanel = uipanel(app.OptionsPanel);
            app.ColorbartypePanel.Title = 'Colorbar type';
            app.colorbar = uiswitch(app.ColorbartypePanel, 'slider');
            app.colorbar.Items = {'Fixed', 'Adaptable'};
            app.colorbar.ValueChangedFcn = createCallbackFcn(app, @MapExtraParameters, true);
            app.colorbar.Value = 'Fixed';
            app.ErosionProfilePanel = uipanel(app.ESDMMUIFigure);
            app.ErosionProfilePanel.Title = 'Erosion Profile';
            app.SinglepointisNofEquatorEofPrimeMeridianPanel = uipanel(app.ErosionProfilePanel);
            app.SinglepointisNofEquatorEofPrimeMeridianPanel.Title = 'Single point (+ is N of Equator & E of Prime Meridian)';
            app.Longitude180to180Label = uilabel(app.SinglepointisNofEquatorEofPrimeMeridianPanel);
            app.Longitude180to180Label.Text = 'Longitude (-180° to 180°)';
            app.longinput = uieditfield(app.SinglepointisNofEquatorEofPrimeMeridianPanel, 'numeric');
            app.longinput.Limits = [-180 180];
            app.longinput.ValueDisplayFormat = '%.2f';
            app.longinput.ValueChangedFcn = createCallbackFcn(app, @ProfileParameters, true);
            app.longinput.Enable = 'off';
            app.Latitude60to75Label = uilabel(app.SinglepointisNofEquatorEofPrimeMeridianPanel);
            app.Latitude60to75Label.Text = 'Latitude (-60° to 75°)';
            app.latinput = uieditfield(app.SinglepointisNofEquatorEofPrimeMeridianPanel, 'numeric');
            app.latinput.Limits = [-60 75];
            app.latinput.ValueDisplayFormat = '%.2f';
            app.latinput.ValueChangedFcn = createCallbackFcn(app, @ProfileParameters, true);
            app.latinput.Enable = 'off';
            app.Allovertheworld26119availablepointsPanel = uipanel(app.ErosionProfilePanel);
            app.Allovertheworld26119availablepointsPanel.Title = 'All over the world (26119 available points)';
            app.NumberofrandompointsEditField_2Label = uilabel(app.Allovertheworld26119availablepointsPanel);
            app.NumberofrandompointsEditField_2Label.Text = 'Number of random points';
            app.numrandall = uieditfield(app.Allovertheworld26119availablepointsPanel, 'numeric');
            app.numrandall.Limits = [1 26119];
            app.numrandall.ValueChangedFcn = createCallbackFcn(app, @ProfileParameters, true);
            app.numrandall.Enable = 'off';
            app.numrandall.Value = 1;
            app.randall = uicheckbox(app.Allovertheworld26119availablepointsPanel);
            app.randall.ValueChangedFcn = createCallbackFcn(app, @ProfileParameters, true);
            app.randall.Enable = 'off';
            app.randall.Text = 'Enable';
            app.Cosmogenicsamplepoints3207samplepointsPanel = uipanel(app.ErosionProfilePanel);
            app.Cosmogenicsamplepoints3207samplepointsPanel.Title = 'Cosmogenic sample points (3207 sample points)';
            app.NumberofrandompointsEditField_3Label = uilabel(app.Cosmogenicsamplepoints3207samplepointsPanel);
            app.NumberofrandompointsEditField_3Label.Text = 'Number of random points';
            app.numrandsample = uieditfield(app.Cosmogenicsamplepoints3207samplepointsPanel, 'numeric');
            app.numrandsample.Limits = [0 3207];
            app.numrandsample.ValueChangedFcn = createCallbackFcn(app, @ProfileParameters, true);
            app.numrandsample.Enable = 'off';
            app.numrandsample.Value = 1;
            app.randsample = uicheckbox(app.Cosmogenicsamplepoints3207samplepointsPanel);
            app.randsample.ValueChangedFcn = createCallbackFcn(app, @ProfileParameters, true);
            app.randsample.Enable = 'off';
            app.randsample.Text = 'Enable';
            app.BasedonStatisticthepeakoftherivererosionratePanel = uipanel(app.ErosionProfilePanel);
            app.BasedonStatisticthepeakoftherivererosionratePanel.Title = 'Based on Statistic @ the peak of the river erosion rate';
            app.maxpos = uicheckbox(app.BasedonStatisticthepeakoftherivererosionratePanel);
            app.maxpos.ValueChangedFcn = createCallbackFcn(app, @ProfileParameters, true);
            app.maxpos.Enable = 'off';
            app.maxpos.Text = '@Max';
            app.medpos = uicheckbox(app.BasedonStatisticthepeakoftherivererosionratePanel);
            app.medpos.ValueChangedFcn = createCallbackFcn(app, @ProfileParameters, true);
            app.medpos.Enable = 'off';
            app.medpos.Text = '@Median(+)';
            app.meanpos = uicheckbox(app.BasedonStatisticthepeakoftherivererosionratePanel);
            app.meanpos.ValueChangedFcn = createCallbackFcn(app, @ProfileParameters, true);
            app.meanpos.Enable = 'off';
            app.meanpos.Text = '@Mean(+)';
            app.modepos = uicheckbox(app.BasedonStatisticthepeakoftherivererosionratePanel);
            app.modepos.ValueChangedFcn = createCallbackFcn(app, @ProfileParameters, true);
            app.modepos.Enable = 'off';
            app.modepos.Text = '@Mode(+)';
            app.minneg = uicheckbox(app.BasedonStatisticthepeakoftherivererosionratePanel);
            app.minneg.ValueChangedFcn = createCallbackFcn(app, @ProfileParameters, true);
            app.minneg.Enable = 'off';
            app.minneg.Text = '@Min';
            app.medov = uicheckbox(app.BasedonStatisticthepeakoftherivererosionratePanel);
            app.medov.ValueChangedFcn = createCallbackFcn(app, @ProfileParameters, true);
            app.medov.Enable = 'off';
            app.medov.Text = '@Median(all)';
            app.meanov = uicheckbox(app.BasedonStatisticthepeakoftherivererosionratePanel);
            app.meanov.ValueChangedFcn = createCallbackFcn(app, @ProfileParameters, true);
            app.meanov.Enable = 'off';
            app.meanov.Text = '@Mean(all)';
            app.modetot = uicheckbox(app.BasedonStatisticthepeakoftherivererosionratePanel);
            app.modetot.ValueChangedFcn = createCallbackFcn(app, @ProfileParameters, true);
            app.modetot.Enable = 'off';
            app.modetot.Text = '@Mode(all)';
            app.medneg = uicheckbox(app.BasedonStatisticthepeakoftherivererosionratePanel);
            app.medneg.ValueChangedFcn = createCallbackFcn(app, @ProfileParameters, true);
            app.medneg.Enable = 'off';
            app.medneg.Text = '@Median(-)';
            app.meanneg = uicheckbox(app.BasedonStatisticthepeakoftherivererosionratePanel);
            app.meanneg.ValueChangedFcn = createCallbackFcn(app, @ProfileParameters, true);
            app.meanneg.Enable = 'off';
            app.meanneg.Text = '@Mean(-)';
            app.modeneg = uicheckbox(app.BasedonStatisticthepeakoftherivererosionratePanel);
            app.modeneg.ValueChangedFcn = createCallbackFcn(app, @ProfileParameters, true);
            app.modeneg.Enable = 'off';
            app.modeneg.Text = '@Mode(-)';
            app.Statistic = uicheckbox(app.BasedonStatisticthepeakoftherivererosionratePanel);
            app.Statistic.ValueChangedFcn = createCallbackFcn(app, @ProfileParameters, true);
            app.Statistic.Enable = 'off';
            app.Statistic.Text = 'Enable';
            app.coordinate = uicheckbox(app.ErosionProfilePanel);
            app.coordinate.ValueChangedFcn = createCallbackFcn(app, @ProfileParameters, true);
            app.coordinate.Enable = 'off';
            app.coordinate.Text = 'Enable';
            app.PrecipitationMapPanel = uipanel(app.ESDMMUIFigure);
            app.PrecipitationMapPanel.Title = 'Precipitation Map';
            app.PLprec = uicheckbox(app.PrecipitationMapPanel);
            app.PLprec.ValueChangedFcn = createCallbackFcn(app, @PrecipitationParameters, true);
            app.PLprec.Enable = 'off';
            app.PLprec.Text = 'PL';
            app.LGMprec = uicheckbox(app.PrecipitationMapPanel);
            app.LGMprec.ValueChangedFcn = createCallbackFcn(app, @PrecipitationParameters, true);
            app.LGMprec.Enable = 'off';
            app.LGMprec.Text = 'LGM';
            app.MHprec = uicheckbox(app.PrecipitationMapPanel);
            app.MHprec.ValueChangedFcn = createCallbackFcn(app, @PrecipitationParameters, true);
            app.MHprec.Enable = 'off';
            app.MHprec.Text = 'MH';
            app.PIprec = uicheckbox(app.PrecipitationMapPanel);
            app.PIprec.ValueChangedFcn = createCallbackFcn(app, @PrecipitationParameters, true);
            app.PIprec.Enable = 'off';
            app.PIprec.Text = 'PI';
            app.PDprec = uicheckbox(app.PrecipitationMapPanel);
            app.PDprec.ValueChangedFcn = createCallbackFcn(app, @PrecipitationParameters, true);
            app.PDprec.Enable = 'off';
            app.PDprec.Text = 'PD';
            app.PLLGMdif = uicheckbox(app.PrecipitationMapPanel);
            app.PLLGMdif.ValueChangedFcn = createCallbackFcn(app, @PrecipitationParameters, true);
            app.PLLGMdif.Enable = 'off';
            app.PLLGMdif.Text = 'PL-LGM';
            app.PLMHdif = uicheckbox(app.PrecipitationMapPanel);
            app.PLMHdif.ValueChangedFcn = createCallbackFcn(app, @PrecipitationParameters, true);
            app.PLMHdif.Enable = 'off';
            app.PLMHdif.Text = 'PL-MH';
            app.PLPIdif = uicheckbox(app.PrecipitationMapPanel);
            app.PLPIdif.ValueChangedFcn = createCallbackFcn(app, @PrecipitationParameters, true);
            app.PLPIdif.Enable = 'off';
            app.PLPIdif.Text = 'PL-PI';
            app.PLPDdif = uicheckbox(app.PrecipitationMapPanel);
            app.PLPDdif.ValueChangedFcn = createCallbackFcn(app, @PrecipitationParameters, true);
            app.PLPDdif.Enable = 'off';
            app.PLPDdif.Text = 'PL-PD';
            app.LGMMHdif = uicheckbox(app.PrecipitationMapPanel);
            app.LGMMHdif.ValueChangedFcn = createCallbackFcn(app, @PrecipitationParameters, true);
            app.LGMMHdif.Enable = 'off';
            app.LGMMHdif.Text = 'LGM-MH';
            app.MHPIdif = uicheckbox(app.PrecipitationMapPanel);
            app.MHPIdif.ValueChangedFcn = createCallbackFcn(app, @PrecipitationParameters, true);
            app.MHPIdif.Enable = 'off';
            app.MHPIdif.Text = 'MH-PI';
            app.LGMPDdif = uicheckbox(app.PrecipitationMapPanel);
            app.LGMPDdif.ValueChangedFcn = createCallbackFcn(app, @PrecipitationParameters, true);
            app.LGMPDdif.Enable = 'off';
            app.LGMPDdif.Text = 'LGM-PD';
            app.LGMPIdif = uicheckbox(app.PrecipitationMapPanel);
            app.LGMPIdif.ValueChangedFcn = createCallbackFcn(app, @PrecipitationParameters, true);
            app.LGMPIdif.Enable = 'off';
            app.LGMPIdif.Text = 'LGM-PI';
            app.MHPDdif = uicheckbox(app.PrecipitationMapPanel);
            app.MHPDdif.ValueChangedFcn = createCallbackFcn(app, @PrecipitationParameters, true);
            app.MHPDdif.Enable = 'off';
            app.MHPDdif.Text = 'MH-PD';
            app.PIPDdif = uicheckbox(app.PrecipitationMapPanel);
            app.PIPDdif.ValueChangedFcn = createCallbackFcn(app, @PrecipitationParameters, true);
            app.PIPDdif.Enable = 'off';
            app.PIPDdif.Text = 'PI-PD';
            app.onefig = uicheckbox(app.PrecipitationMapPanel);
            app.onefig.ValueChangedFcn = createCallbackFcn(app, @PrecipitationParameters, true);
            app.onefig.Text = 'In one figure';
            app.sepfig = uicheckbox(app.PrecipitationMapPanel);
            app.sepfig.ValueChangedFcn = createCallbackFcn(app, @PrecipitationParameters, true);
            app.sepfig.Text = 'In seperated figures';
            app.RealtimemapselectedpointsandareaPanel = uipanel(app.ESDMMUIFigure);
            app.RealtimemapselectedpointsandareaPanel.Title = 'Real-time map (selected points and area)';
            app.UIAxes = uiaxes(app.RealtimemapselectedpointsandareaPanel);
            app.UIAxes.XLim = [-180 180];
            app.UIAxes.YLim = [-90 90];
            app.UIAxes.XTick = [-180 -150 -120 -90 -60 -30 0 30 60 90 120 150 180];
            app.UIAxes.XTickLabel = {'-180'; '-150'; '-120'; '-90'; '-60'; '-30'; '0'; '30'; '60'; '90'; '120'; '150'; '180'};
            app.UIAxes.YTick = [-90 -60 -30 0 30 60 90];
            app.UIAxes.YTickLabel = {'-90'; '-60'; '-30'; '0'; '30'; '60'; '90'};
            app.runpanel = uipanel(app.ESDMMUIFigure);
            app.SavefolderButtonGroup = uibuttongroup(app.runpanel);
            app.SavefolderButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RUNpanel, true);
            app.SavefolderButtonGroup.Title = 'Save folder';
            app.AlltogetherButton = uiradiobutton(app.SavefolderButtonGroup);
            app.AlltogetherButton.Enable = 'off';
            app.AlltogetherButton.Text = 'All together';
            app.AlltogetherButton.Value = true;
            app.CategorizedButton = uiradiobutton(app.SavefolderButtonGroup);
            app.CategorizedButton.Enable = 'off';
            app.CategorizedButton.Text = 'Categorized';
            app.JPG = uicheckbox(app.runpanel);
            app.JPG.ValueChangedFcn = createCallbackFcn(app, @RUNpanel, true);
            app.JPG.Enable = 'off';
            app.JPG.Text = '*.jpg';
            app.BMP = uicheckbox(app.runpanel);
            app.BMP.ValueChangedFcn = createCallbackFcn(app, @RUNpanel, true);
            app.BMP.Enable = 'off';
            app.BMP.Text = '*.bmp';
            app.PDF = uicheckbox(app.runpanel);
            app.PDF.ValueChangedFcn = createCallbackFcn(app, @RUNpanel, true);
            app.PDF.Enable = 'off';
            app.PDF.Text = '*.pdf';
            app.SVG = uicheckbox(app.runpanel);
            app.SVG.ValueChangedFcn = createCallbackFcn(app, @RUNpanel, true);
            app.SVG.Enable = 'off';
            app.SVG.Text = '*.svg';
            app.PLOTCheckBox = uicheckbox(app.runpanel);
            app.PLOTCheckBox.ValueChangedFcn = createCallbackFcn(app, @RUNpanel, true);
            app.PLOTCheckBox.Text = 'PLOT';
            app.PLOTCheckBox.Value = true;
            app.SAVECheckBox = uicheckbox(app.runpanel);
            app.SAVECheckBox.ValueChangedFcn = createCallbackFcn(app, @RUNpanel, true);
            app.SAVECheckBox.Text = 'SAVE';
            app.RUN = uibutton(app.runpanel, 'push');
            app.RUN.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUN.Enable = 'off';
            app.RUN.Text = 'RUN';
            app.PlothistogramPanel = uipanel(app.ESDMMUIFigure);
            app.PlothistogramPanel.Title = 'Plot histogram';
            app.hist1 = uicheckbox(app.PlothistogramPanel);
            app.hist1.ValueChangedFcn = createCallbackFcn(app, @Histogram_panel, true);
            app.hist1.Enable = 'off';
            app.hist1.Text = 'Plot ratio of: RT@PEAK of CE / observed RT';
            app.hist2 = uicheckbox(app.PlothistogramPanel);
            app.hist2.ValueChangedFcn = createCallbackFcn(app, @Histogram_panel, true);
            app.hist2.Enable = 'off';
            app.hist2.Text = 'Plot ratio of: Peak of RE / Peak of CE ';
        end
    end

    methods (Access = public)

        % Construct app
        function app = ESD

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ESDMMUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ESDMMUIFigure)
        end
    end
end