classdef ESD < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ESDMMUIFigure                  matlab.ui.Figure
        Menu                           matlab.ui.container.Menu
        AbbreviationsMenu              matlab.ui.container.Menu
        HelpveryimportantMenu          matlab.ui.container.Menu
        ContactusMenu                  matlab.ui.container.Menu
        CloseallfiguresMenu            matlab.ui.container.Menu
        ExitappMenu                    matlab.ui.container.Menu
        ButtonGroup                    matlab.ui.container.ButtonGroup
        LampSTOP                       matlab.ui.control.Lamp
        Cancel                         matlab.ui.control.RockerSwitch
        LampRUN                        matlab.ui.control.Lamp
        RUN                            matlab.ui.control.Button
        loadinglabel                   matlab.ui.control.Label
        loadingbar                     matlab.ui.control.LinearGauge
        PlotHistogramPanel             matlab.ui.container.Panel
        hist2                          matlab.ui.control.CheckBox
        hist1                          matlab.ui.control.CheckBox
        runpanel                       matlab.ui.container.Panel
        SaveformatButtonGroup          matlab.ui.container.ButtonGroup
        SVG                            matlab.ui.control.CheckBox
        PDF                            matlab.ui.control.CheckBox
        BMP                            matlab.ui.control.CheckBox
        JPG                            matlab.ui.control.CheckBox
        SAVECheckBox                   matlab.ui.control.CheckBox
        PLOTCheckBox                   matlab.ui.control.CheckBox
        SavefolderButtonGroup          matlab.ui.container.ButtonGroup
        CategorizedButton              matlab.ui.control.RadioButton
        AlltogetherButton              matlab.ui.control.RadioButton
        RealtimemapselectedpointsandareaPanel  matlab.ui.container.Panel
        UIAxes                         matlab.ui.control.UIAxes
        NoneErosionMapmeanannualPanel  matlab.ui.container.Panel
        TemperatureMap                 matlab.ui.control.CheckBox
        PrecipitationMap               matlab.ui.control.CheckBox
        sepfig                         matlab.ui.control.CheckBox
        onefig                         matlab.ui.control.CheckBox
        PIPDdif                        matlab.ui.control.CheckBox
        MHPDdif                        matlab.ui.control.CheckBox
        LGMPIdif                       matlab.ui.control.CheckBox
        LGMPDdif                       matlab.ui.control.CheckBox
        MHPIdif                        matlab.ui.control.CheckBox
        LGMMHdif                       matlab.ui.control.CheckBox
        PLPDdif                        matlab.ui.control.CheckBox
        PLPIdif                        matlab.ui.control.CheckBox
        PLMHdif                        matlab.ui.control.CheckBox
        PLLGMdif                       matlab.ui.control.CheckBox
        PDprec                         matlab.ui.control.CheckBox
        PIprec                         matlab.ui.control.CheckBox
        MHprec                         matlab.ui.control.CheckBox
        LGMprec                        matlab.ui.control.CheckBox
        PLprec                         matlab.ui.control.CheckBox
        ErosionProfilePanel            matlab.ui.container.Panel
        coordinate                     matlab.ui.control.CheckBox
        BasedonStatisticthepeakoftherivererosionratePanel  matlab.ui.container.Panel
        Statistic                      matlab.ui.control.CheckBox
        modeneg                        matlab.ui.control.CheckBox
        meanneg                        matlab.ui.control.CheckBox
        medneg                         matlab.ui.control.CheckBox
        modetot                        matlab.ui.control.CheckBox
        meanov                         matlab.ui.control.CheckBox
        medov                          matlab.ui.control.CheckBox
        minneg                         matlab.ui.control.CheckBox
        modepos                        matlab.ui.control.CheckBox
        meanpos                        matlab.ui.control.CheckBox
        medpos                         matlab.ui.control.CheckBox
        maxpos                         matlab.ui.control.CheckBox
        Cosmogenicsamplepoints3207samplepointsPanel  matlab.ui.container.Panel
        randsample                     matlab.ui.control.CheckBox
        numrandsample                  matlab.ui.control.NumericEditField
        NumberofrandompointsEditField_3Label  matlab.ui.control.Label
        Allovertheworld26119availablepointsPanel  matlab.ui.container.Panel
        randall                        matlab.ui.control.CheckBox
        numrandall                     matlab.ui.control.NumericEditField
        NumberofrandompointsEditField_2Label  matlab.ui.control.Label
        SinglepointisNofEquatorEofPrimeMeridianPanel  matlab.ui.container.Panel
        latinput                       matlab.ui.control.NumericEditField
        Latitude60to75Label            matlab.ui.control.Label
        longinput                      matlab.ui.control.NumericEditField
        Longitude180to180Label         matlab.ui.control.Label
        OptionsPanel                   matlab.ui.container.Panel
        ColorbartypeButtonGroup        matlab.ui.container.ButtonGroup
        CT_Eqdstrbtn                   matlab.ui.control.RadioButton
        CT_EqDstnc                     matlab.ui.control.RadioButton
        CT_Fixed                       matlab.ui.control.RadioButton
        PlotareaPanel                  matlab.ui.container.Panel
        WorldCo_2                      matlab.ui.control.Button
        SouthAmericaCo_4               matlab.ui.control.Button
        WorldCo_6                      matlab.ui.control.Button
        WorldCo_4                      matlab.ui.control.Button
        SouthAmericaCo                 matlab.ui.control.Button
        SouthAmericaCo_3               matlab.ui.control.Button
        WorldCo_3                      matlab.ui.control.Button
        SouthAmericaCo_2               matlab.ui.control.Button
        WorldCo_5                      matlab.ui.control.Button
        WorldCo                        matlab.ui.control.Button
        TRlat                          matlab.ui.control.NumericEditField
        LatEditField_2Label            matlab.ui.control.Label
        TRlong                         matlab.ui.control.NumericEditField
        LonLabel_2                     matlab.ui.control.Label
        LBlong                         matlab.ui.control.NumericEditField
        LonLabel                       matlab.ui.control.Label
        LBlat                          matlab.ui.control.NumericEditField
        LatEditFieldLabel              matlab.ui.control.Label
        TextArea_2                     matlab.ui.control.TextArea
        TextArea                       matlab.ui.control.TextArea
        ice                            matlab.ui.control.CheckBox
        sample                         matlab.ui.control.CheckBox
        stat                           matlab.ui.control.CheckBox
        border                         matlab.ui.control.CheckBox
        ErosionMapPanel                matlab.ui.container.Panel
        atPanel                        matlab.ui.container.Panel
        Apeak                          matlab.ui.control.CheckBox
        Bpeak                          matlab.ui.control.CheckBox
        atCEPeak50                     matlab.ui.control.CheckBox
        atCEPeak40                     matlab.ui.control.CheckBox
        atCEPeak30                     matlab.ui.control.CheckBox
        atCEPeak20                     matlab.ui.control.CheckBox
        atCEPeak10                     matlab.ui.control.CheckBox
        atPeakCE                       matlab.ui.control.CheckBox
        atCEUi50                       matlab.ui.control.CheckBox
        atCEUi40                       matlab.ui.control.CheckBox
        atCEUi30                       matlab.ui.control.CheckBox
        atCEUi20                       matlab.ui.control.CheckBox
        atCEUi10                       matlab.ui.control.CheckBox
        atPeakRE                       matlab.ui.control.CheckBox
        atREPeak50                     matlab.ui.control.CheckBox
        atREPeak40                     matlab.ui.control.CheckBox
        atREPeak30                     matlab.ui.control.CheckBox
        atREPeak20                     matlab.ui.control.CheckBox
        atREPeak10                     matlab.ui.control.CheckBox
        atREUi10                       matlab.ui.control.CheckBox
        atREUi20                       matlab.ui.control.CheckBox
        atREUi30                       matlab.ui.control.CheckBox
        atREUi40                       matlab.ui.control.CheckBox
        atREUi50                       matlab.ui.control.CheckBox
        atEnd                          matlab.ui.control.CheckBox
        atTT                           matlab.ui.control.CheckBox
        MapcategoriesPanel             matlab.ui.container.Panel
        CE                             matlab.ui.control.CheckBox
        KPT                            matlab.ui.control.CheckBox
        CE_Ui                          matlab.ui.control.CheckBox
        CE_Peak                        matlab.ui.control.CheckBox
        RE_Peak                        matlab.ui.control.CheckBox
        RE_Ui                          matlab.ui.control.CheckBox
        RE                             matlab.ui.control.CheckBox
        RT                             matlab.ui.control.CheckBox
        NumberoffiguresPanel           matlab.ui.container.Panel
        totaltemperature               matlab.ui.control.NumericEditField
        TemperaturemapLabel            matlab.ui.control.Label
        totalhistogarm                 matlab.ui.control.NumericEditField
        PlothistogramLabel             matlab.ui.control.Label
        totalprecipitation             matlab.ui.control.NumericEditField
        PrecipitationmapLabel          matlab.ui.control.Label
        totalmap                       matlab.ui.control.NumericEditField
        ErosionmapLabel                matlab.ui.control.Label
        totalprofile                   matlab.ui.control.NumericEditField
        ErosionprofileLabel            matlab.ui.control.Label
        DataPanel                      matlab.ui.container.Panel
        LampGM                         matlab.ui.control.Lamp
        LampRM                         matlab.ui.control.Lamp
        N_map                          matlab.ui.control.NumericEditField
        DataLabel                      matlab.ui.control.Label
        CatchmentparametersPanel       matlab.ui.container.Panel
        KErodibilitymyr05Panel         matlab.ui.container.Panel
        K17                            matlab.ui.control.CheckBox
        K16                            matlab.ui.control.CheckBox
        K55                            matlab.ui.control.CheckBox
        UiInitialrockupliftmmyrPanel   matlab.ui.container.Panel
        Ui05                           matlab.ui.control.CheckBox
        Ui1                            matlab.ui.control.CheckBox
        Ui001                          matlab.ui.control.CheckBox
        Ui01                           matlab.ui.control.CheckBox
        LRiverlengthkmPanel            matlab.ui.container.Panel
        L80                            matlab.ui.control.CheckBox
        L40                            matlab.ui.control.CheckBox
        L10                            matlab.ui.control.CheckBox
        TimeperiodsPanel               matlab.ui.container.Panel
        TnFinalPanel                   matlab.ui.container.Panel
        PDn                            matlab.ui.control.CheckBox
        PIn                            matlab.ui.control.CheckBox
        MHn                            matlab.ui.control.CheckBox
        LGMn                           matlab.ui.control.CheckBox
        TiInitialPanel                 matlab.ui.container.Panel
        PIi                            matlab.ui.control.CheckBox
        MHi                            matlab.ui.control.CheckBox
        LGMi                           matlab.ui.control.CheckBox
        PLi                            matlab.ui.control.CheckBox
    end

    properties (Access = public)
        MainPath % App parent directory
        
        PathExtraData
        PathBriefData
        PathPrecipitationData
        PathTemperatureData

        PrecipitationDir
        TemperatureDir
        
        BriefData
        PrecipitationData
        TemperatureData
        
        LatLongAlt % latitude longitude altitude
        
        SMPL
        A3
        ICE_LOC
        ICE_LOC_full
        COSM
        wplot
        scb %switch colorbar (fixed = 0 & adaptable = 1)
        cstlat
        cstlon
        brdrs % country borders
        
        allpnts % row of the all selected points for profile plot
        alltype % type of them
        allIPT % method
        
        % Check boxes
        vTPi                % initial time chekcbox value
        vTPn                % final time checkbox value
        vL                  % River length checkbox value
        vUi                 % Initial rock uplift checkbox value
        vK                  % bedrock detachment checkbox value
        vMT                 % map type checkbox value
        vatG                % @ General
        vatRE_Ui            % @ Change in RE(Ui)
        vatRE_Peak          % @ Change in RE(Peak)
        vatCE_BAP           % @ Change in CE Befor Peak & Change in CE After Peak
        vatCE_Ui            % @ Change in CE(Ui)
        vatCE_Peak          % @ Change in CE(Peak)
        vExt                % Options panel
        vat_all             % [vatG, vatRE_Ui, vatRE_Peak, vatCE_Ui, vatCE_Peak]             
        vPP                 % change in how to pick points in profiles
        vststPr             % pick value based on statistic
        vsmplrnd            % number of random points from the samples
        vallrnd             % number of random points from all points
        vcord               % lat & lon
        PrecMap             % Choose precipitation map
        TempMap             % Choose temperature map
        precHOW             % plot precipitation/temperature maps in one figure & plot precipitation maps in seperated figures
        prec                % Precipitation/temperature time period
        difprec             % Preciitation/temperature difference time period
        pltHIST             % plot histogram
        
        RP                  % Run panel
        
        InputData           % selected data based on user input from data panel
        
        plt_type 
        plt_at
        plt_xt
        
        Nreal
        Ntot
        
        user_cancel
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Startup script
            %----------------------------- Fix and locate the path
            if ismac && isdeployed % for mac
                app.MainPath = '/Applications/ESD_Tuebingen/application';
                MP = app.MainPath;
                cd(MP);
            else % for ther OS
                app.MainPath = pwd;
                MP = app.MainPath;
            end
            %----------------------- sort file paths
            app.PathExtraData = strcat(MP,filesep,'ExtraData');
            app.PathPrecipitationData = strcat(MP,filesep,'PrecipitationData');
            app.PathTemperatureData = strcat(MP,filesep,'TemperatureData');
            app.PathBriefData = strcat(MP,filesep,'BriefData');
            
            PathBorders = strcat(app.PathExtraData,filesep,'Country_Border.mat'); % Path of the country borders
            PathLLA = strcat(app.PathExtraData,filesep,'LatLongAlt.mat'); % Path of the the lat,lon,alt of all the data points
            PathCosmo = strcat(app.PathExtraData,filesep,'OCTOPUSCRN_resp_times_v180623.xlsx'); % Path of the Cosmo data
            PathLGM = strcat(app.PathExtraData,filesep,'T159_jan_surf_lgm_veg.nc'); % Path of the LGM ice cover
            PathCosmoCeof = strcat(app.PathExtraData,filesep,'LatLongAltSF123.txt'); % Path of the Cosmo coef.
            %----------------------------- % load the country borders data and plot them on realtime map
            load(PathBorders); % Load the country borders
            app.brdrs = Borders;
            rectangle(app.UIAxes,'Position',[-180 -90 360 180],'FaceColor',[0 0 0 0.7],'EdgeColor','none');
            hold(app.UIAxes,'on');
            rectangle(app.UIAxes,'Position',[-180 -60 360 135],'FaceColor',[0 0 1 0.6],'EdgeColor','none');
            plot(app.UIAxes,Borders(2,:),Borders(1,:),'k','Linewidth',0.1);
            %----------------------------- % welcome text (just at program startup)
            tmp = "Welcome to ESD APP\n----------\nWritten and designed by:\nM.Sc. Mohammadreza Ershadi\n"+...
                  "Mohammadreza.Ershadi@uni.tuebingen.de\nAt Earth Shape Dynamics Reasearch Group (UniTübingen)\n----------\n"+...
                  "Please read HELP (Ctrl+H) & ABBREVIATIONS (Ctrl+I)"+...
                  " in the Extra tab of the Menu bar befoe using the program\n"+...
                  "----------\nIn case of any question or problem please do not hesitate to contact us (Ctrl+C)\n----------\nEnJoY! :)"+...
                  "\n\nCurrentFolder:\n";
            wlcmTXT = sprintf(tmp+"%s",MP);
            %---------------------------------------------Creat a waitbar (initial data loading)
            uiwait(msgbox(char(wlcmTXT),'Welcome','modal'));
            wb = waitbar(0,'Preparing Initial Data...','Name','Loading Initial Data');
            pause(.5)
            try
                %--------------------------------------------- step 0 (loading BreifData)
                perc = 0/7;
                txt = "Loading a brief version of erosion data... " + string(round(perc*100,1)) + " %";
                waitbar(perc,wb,txt);
                pause(.5)
                % extract the apth of all the brief data and store it in the "app"
                app.BriefData = dir(strcat(app.PathBriefData,filesep,'*BriefOutput*.mat'));
                %--------------------------------------------- step 1 (loading precipitation data)
                perc = 1/7;
                txt = "Loading Precipitation Data... " + string(round(perc*100,1)) + " %";
                waitbar(perc,wb,txt);
                pause(.5)
                PrcDta = dir(strcat(app.PathPrecipitationData,filesep,'*oceanmasked*.txt')); % extract the path of the precipitation data
                for i = 1:size(PrcDta,1) % load the oceanmasked precipitation data from the path
                    tmp_data = load(strcat(app.PathPrecipitationData,filesep,PrcDta(i).name));
                    % data = [lat      lon        p_PL        p_LGM       p_MH      p_PI      p_PD]
                    data(:,1:2) = tmp_data(:,1:2); 
                    data(:,i+2) = tmp_data(:,3);   
                end
                app.PrecipitationDir = PrcDta; % store the path of the oceanmasked precipitation data in the "app"
                app.PrecipitationData = data;  % store the oceanmasked precipitation data in the "app"
                %--------------------------------------------- step 2 (loading temperature data)
                perc = 2/7;
                txt = "Loading Temperature Data... " + string(round(perc*100,1)) + " %";
                waitbar(perc,wb,txt);
                pause(.5)
                TmpDta = dir(strcat(app.PathTemperatureData,filesep,'*oceanmasked*.txt')); % extract the path of the temperature data
                for i = 1:size(TmpDta,1) % load the oceanmasked temperature data from the path
                    tmp_data = load(strcat(app.PathTemperatureData,filesep,TmpDta(i).name));
                    % data = [lat      lon        t_PL        t_LGM       t_MH      t_PI      t_PD]
                    data(:,1:2) = tmp_data(:,1:2); 
                    data(:,i+2) = tmp_data(:,3);   
                end
                app.TemperatureDir = TmpDta; % store the path of the oceanmasked temperature data in the "app"
                app.TemperatureData = data;  % store the oceanmasked temperature data in the "app"
                %--------------------------------------------- step 3 (loading latitude,longitude,altitude)
                perc = 3/7;
                txt = "Loading Lat,Long,Alt... " + string(round(perc*100,1)) + " %";
                waitbar(perc,wb,txt);
                pause(.5)
                LLA = load(PathLLA);
                lat = LLA.LatLongAlt(:,1); % get the latitude
                long= LLA.LatLongAlt(:,2); % get the longitude
                app.LatLongAlt = LLA.LatLongAlt; % store the lat,lon,alt in the "app"
                %--------------------------------------------- step 4 (loading cosmo sample points)
                perc = 4/7;
                txt = "Loading cosmogenic sample data... " + string(round(perc*100,1)) + " %";
                waitbar(perc,wb,txt);
                pause(.5)
                % load the lat,lon and data of the cosmo sample points
                smpl(:,1) = xlsread(PathCosmo,1,'C2:C3208'); % latitude
                smpl(:,2) = xlsread(PathCosmo,1,'D2:D3208'); % longitude
                smpl(:,3) = xlsread(PathCosmo,1,'L2:L3208'); % resp_time_soil_kyr
                % find the closest locatopn to the sample and pick the
                % calculated value to compare with the sample
                for i = 1:size(smpl,1)
                    lat_usr = smpl(i,1);
                    long_usr = smpl(i,2);
                    A(:,1) = abs(lat_usr - lat);
                    A(:,2) = abs(long_usr - long);
                    A(:,3) = sqrt((A(:,1).^2) + (A(:,2).^2));
                    [~,p] = min(A(:,3));
                    smpl(i,4) = p;
                end
                a3 = smpl(:,4);
                smpl(:,5) = lat(a3);
                smpl(:,6) = long(a3);
                app.SMPL = smpl;
                app.A3 = a3; 
                %--------------------------------------------- step 5 (loading LGM ice cover)
                perc = 5/7;
                txt = "Loading LGM Ice Cover Data... " + string(round(perc*100,1)) + " %";
                waitbar(perc,wb,txt);
                pause(.5)
                GLAC_lat = ncread(PathLGM,'lat');
                GLAC_lon = ncread(PathLGM,'lon');
                GLAC = ncread(PathLGM,'GLAC');
                GLAC = GLAC';
                [msh_lon,msh_lat] = meshgrid (GLAC_lon,GLAC_lat);
                A=find(GLAC==1);    
                GLT = msh_lat(A);
                GLN = msh_lon(A);
                ice_loc = [GLT,GLN];
                ic_tmp1 = find(ice_loc(:,2) > 180);
                ic_tmp2 = ice_loc;
                ic_tmp2(ic_tmp1,2) = ic_tmp2(ic_tmp1,2) - 360;
                app.ICE_LOC_full = ic_tmp2;
                A = find(ice_loc(:,1)>75 | ice_loc(:,1)<-60);
                ice_loc(A,:) = [];
                app.ICE_LOC = ice_loc;
                %--------------------------------------------- step 6 (loading cosmogenic coefficients)
                perc = 6/7;
                txt = "Loading Cosmogenic Coefficients Data... " + string(round(perc*100,1)) + " %";
                waitbar(perc,wb,txt);
                pause(.5)
                cosmovar = load(PathCosmoCeof);
                app.COSM = cosmovar;
                %---------------------------------------------
                perc = 7/7;
                txt = "All Done... " + string(round(perc*100,1)) + " %";
                waitbar(perc,wb,txt);
                pause(.5)
                close(wb);
                uiwait(msgbox('READY TO USE','Success','modal'));
            catch ME
                close(wb);
                uiwait(msgbox('Missing data','Error!!!','modal'));
                uiwait(msgbox(ME.message,'Matlab Error','modal'));
                rethrow(ME)
            end
        end

        % Value changed function: K16, K17, K55, L10, L40, L80, LGMi, 
        % ...and 11 other components
        function SelectDataParameters(app, event)
% Data selection checkboxes
            %--------------Check all the check boxes and options to update them
            app_FUNC_GeneralCheckBox(app);
            %--------------update real-time map and pick selected points (only for profile)
            app_FUNC_realtimemap(app)
        end

        % Value changed function: CE, CE_Peak, CE_Ui, KPT, RE, RE_Peak, 
        % ...and 2 other components
        function MapCategoryParameters(app, event)
% map type checkboxes
            %--------------Check all the check boxes and options to update them
            app_FUNC_GeneralCheckBox(app);
            %--------------update real-time map and pick selected points (only for profile)
            app_FUNC_realtimemap(app)
        end

        % Value changed function: Apeak, Bpeak, atCEPeak10, atCEPeak20, 
        % ...and 22 other components
        function MapAtParameters(app, event)
% @ checkboxes
            %--------------Check all the check boxes and options to update them
            app_FUNC_GeneralCheckBox(app);
            %--------------update real-time map and pick selected points (only for profile)
            app_FUNC_realtimemap(app)
        end

        % Value changed function: LBlat, LBlong, TRlat, TRlong, border, 
        % ...and 3 other components
        function MapExtraParameters(app, event)
% Extra and Save checkboxes
            %--------------Check all the check boxes and options to update them
            app_FUNC_GeneralCheckBox(app);
            %--------------update real-time map and pick selected points (only for profile)
            app_FUNC_realtimemap(app)
        end

        % Value changed function: Statistic, coordinate, latinput, 
        % ...and 16 other components
        function ProfileParameters(app, event)
            %--------------Check all the check boxes and options to update them
            app_FUNC_GeneralCheckBox(app);
            %--------------update real-time map and pick selected points (only for profile)
            app_FUNC_realtimemap(app)
        end

        % Value changed function: LGMMHdif, LGMPDdif, LGMPIdif, LGMprec, 
        % ...and 15 other components
        function NoneErosionMaps(app, event)
            %--------------Check all the check boxes and options to update them
            app_FUNC_GeneralCheckBox(app);
            %--------------update real-time map and pick selected points (only for profile)
            app_FUNC_realtimemap(app)
        end

        % Menu selected function: HelpveryimportantMenu
        function HELP(app, event)
            % open the help file
            if ispc
                cd('ExtraData')
                winopen('HELP.pdf')
            elseif ismac
                P4 = '/Applications/ESD_Tuebingen/application/ExtraData';
                cd(P4)
                macopen('HELP.pdf')
            end
            cd(app.MainPath)
        end

        % Menu selected function: ContactusMenu
        function ABOUT(app, event)
            % about (menu tab)
            aboutTXT = sprintf("Todd.Ehlers@uni-tuebingen.de\n\nMohammadreza.Ershadi@uni-tuebingen.de");
            msgbox(aboutTXT);
        end

        % Menu selected function: AbbreviationsMenu
        function AbbreviationsMenuSelected(app, event)
            % abbreviations (menu tab)
            tmp =   "----------\n" + ...
                    "Time periods panel:\n" + ...
                    "PL: Pliocene\n" + ...
                    "LGM: Last-Glacial-Maximum\n" + ...
                    "MH: Mid-Holocene\n" + ...
                    "PI: Pre-Industrial\n" + ...
                    "PD: Presend-Day\n"+...
                    "----------\n" + ...
                    "Map categories panel:\n" + ...
                    "RT: Response Time\n" + ...
                    "RE: mean catchment River Erosion rate\n" + ...
                    "RE(Ui): change in RE relative to Ui\n"+...
                    "RE(Peak): change in RE relative to Peak\n" + ...
                    "CE: mean catchment Cosmogenic Erosion rate\n"+...
                    "CE(Ui): change in CE relative to Ui\n" + ...
                    "CE(Peak): change in CE relative to Peak\n"+...
                    "KPT: lateral shift in Knickpoint position\n" + ...
                    "----------\n" + ...
                    "At what point panel:\n" + ...
                    "@Tn: at the end of the model\n" + ...
                    "@N.S.S.: at new steady state\n"+...
                    "@Peak RE: at peak of RE\n" + ...
                    "@10%% RE(Peak): when RE is at 10%% of its peak\n" + ...
                    "@10%% RE(UI): when RE is at 10%% of UI\n" +...
                    "@peak CE: at peak of CE\n" + ...
                    "@10%% CE(Peak): when CE is at 10%% of its peak\n" + ...
                    "@10%% CE(UI): when CE is at 10%% of UI";
            abbrTXT = sprintf(tmp);
            helpdlg(abbrTXT,"Abbreviations");
        end

        % Button pushed function: WorldCo
        function WorldCoButtonPushed(app, event)
            app.LBlat.Value = -60;
            app.LBlong.Value = -180;
            app.TRlat.Value = 75;
            app.TRlong.Value = 180;
            %--------------Check all the check boxes and options to update them
            app_FUNC_GeneralCheckBox(app);
            %--------------update real-time map and pick selected points (only for profile)
            app_FUNC_realtimemap(app)
        end

        % Button pushed function: SouthAmericaCo
        function SouthAmericaCoButtonPushed(app, event)
            app.LBlat.Value = -58;
            app.LBlong.Value = -83;
            app.TRlat.Value = 14;
            app.TRlong.Value = -33;
            %--------------Check all the check boxes and options to update them
            app_FUNC_GeneralCheckBox(app);
            %--------------update real-time map and pick selected points (only for profile)
            app_FUNC_realtimemap(app)
        end

        % Button pushed function: WorldCo_2
        function WorldCo_2ButtonPushed(app, event)
            app.LBlat.Value = 47;
            app.LBlong.Value = 5.5;
            app.TRlat.Value = 55.5;
            app.TRlong.Value = 15.5;
            %--------------Check all the check boxes and options to update them
            app_FUNC_GeneralCheckBox(app);
            %--------------update real-time map and pick selected points (only for profile)
            app_FUNC_realtimemap(app)
        end

        % Callback function: BMP, JPG, PDF, PLOTCheckBox, SAVECheckBox,
        % SVG, 
        % ...and 1 other component
        function RUNpanel(app, event)
            %--------------Check all the check boxes and options to update them
            app_FUNC_GeneralCheckBox(app);
            %--------------update real-time map and pick selected points (only for profile)
%             app_FUNC_realtimemap(app)
        end

        % Button pushed function: RUN
        function RUNButtonPushed(app, event)
            %--------------Check all the check boxes and options to update them
            app_FUNC_GeneralCheckBox(app);
            %--------------update real-time map and pick selected points (only for profile)
%             app_FUNC_realtimemap(app)
            %--------------
            app_FUNC_pushbut(app);
        end

        % Button pushed function: WorldCo_3
        function WorldCo_3ButtonPushed(app, event)
            app.LBlat.Value = -46;
            app.LBlong.Value = 110;
            app.TRlat.Value = -9;
            app.TRlong.Value = 156;
            %--------------Check all the check boxes and options to update them
            app_FUNC_GeneralCheckBox(app);
            %--------------update real-time map and pick selected points (only for profile)
            app_FUNC_realtimemap(app)
        end

        % Button pushed function: WorldCo_4
        function WorldCo_4ButtonPushed(app, event)
            app.LBlat.Value = -41;
            app.LBlong.Value = -20;
            app.TRlat.Value = 42;
            app.TRlong.Value = 58;
            %--------------Check all the check boxes and options to update them
            app_FUNC_GeneralCheckBox(app);
            %--------------update real-time map and pick selected points (only for profile)
            app_FUNC_realtimemap(app)
        end

        % Button pushed function: SouthAmericaCo_2
        function SouthAmericaCo_2ButtonPushed(app, event)
            app.LBlat.Value = 10;
            app.LBlong.Value = -168;
            app.TRlat.Value = 75;
            app.TRlong.Value = -20;
            %--------------Check all the check boxes and options to update them
            app_FUNC_GeneralCheckBox(app);
            %--------------update real-time map and pick selected points (only for profile)
            app_FUNC_realtimemap(app)
        end

        % Button pushed function: SouthAmericaCo_3
        function SouthAmericaCo_3ButtonPushed(app, event)
            app.LBlat.Value = 35;
            app.LBlong.Value = -25;
            app.TRlat.Value = 75;
            app.TRlong.Value = 50;
            %--------------Check all the check boxes and options to update them
            app_FUNC_GeneralCheckBox(app);
            %--------------update real-time map and pick selected points (only for profile)
            app_FUNC_realtimemap(app)
        end

        % Button pushed function: SouthAmericaCo_4
        function SouthAmericaCo_4ButtonPushed(app, event)
            app.LBlat.Value = -13;
            app.LBlong.Value = 40;
            app.TRlat.Value = 75;
            app.TRlong.Value = 180;
            %--------------Check all the check boxes and options to update them
            app_FUNC_GeneralCheckBox(app);
            %--------------update real-time map and pick selected points (only for profile)
            app_FUNC_realtimemap(app)
        end

        % Button pushed function: WorldCo_5
        function WorldCo_5ButtonPushed(app, event)
            app.LBlat.Value = -45;
            app.LBlong.Value = -80;
            app.TRlat.Value = -10;
            app.TRlong.Value = -60;
            %--------------Check all the check boxes and options to update them
            app_FUNC_GeneralCheckBox(app);
            %--------------update real-time map and pick selected points (only for profile)
            app_FUNC_realtimemap(app)
        end

        % Button pushed function: WorldCo_6
        function WorldCo_6ButtonPushed(app, event)
            app.LBlat.Value = 26;
            app.LBlong.Value = -130;
            app.TRlat.Value = 52;
            app.TRlong.Value = -50;
            %--------------Check all the check boxes and options to update them
            app_FUNC_GeneralCheckBox(app);
            %--------------update real-time map and pick selected points (only for profile)
            app_FUNC_realtimemap(app)
        end

        % Value changed function: hist1, hist2
        function Histogram_panel(app, event)
            %--------------Check all the check boxes and options to update them
            app_FUNC_GeneralCheckBox(app);
            %--------------update real-time map and pick selected points (only for profile)
            app_FUNC_realtimemap(app)
        end

        % Value changed function: Cancel
        function CancelValueChanged(app, event)
            value = app.Cancel.Value;
            if value == "Stop"
                app.user_cancel = 1;
                app.loadinglabel.FontColor = [1.00 1.00 0.00];
                app.loadinglabel.Text = "Cancelling the operation !!! :-(";
                app.LampRUN.Enable = 'off';
                app.LampSTOP.Enable = 'on';
                pause(0.1);
            else
                app.user_cancel = 0;
                app.loadingbar.Value = 0;
                app.loadinglabel.FontColor = [0.00 1.00 0.00];
                app.loadinglabel.Text = "...";
                app.LampRUN.Enable = 'off';
                app.LampSTOP.Enable = 'off';
            end
        end

        % Menu selected function: CloseallfiguresMenu
        function CloseallfiguresMenuSelected(app, event)
            close all
            app.user_cancel = 0;
            app.loadingbar.Value = 0;
            app.loadinglabel.FontColor = [0.00 1.00 0.00];
            app.loadinglabel.Text = "...";
            app.LampRUN.Enable = 'off';
            app.LampSTOP.Enable = 'off';
        end

        % Menu selected function: ExitappMenu
        function ExitappMenuSelected(app, event)
            close all force
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ESDMMUIFigure = uifigure('Visible', 'off');
            app.ESDMMUIFigure.Name = 'ESD.M.M';
            app.Menu = uimenu(app.ESDMMUIFigure);
            app.Menu.Text = 'Menu';
            app.AbbreviationsMenu = uimenu(app.Menu);
            app.AbbreviationsMenu.MenuSelectedFcn = createCallbackFcn(app, @AbbreviationsMenuSelected, true);
            app.AbbreviationsMenu.Accelerator = 'A';
            app.AbbreviationsMenu.Text = 'Abbreviations';
            app.HelpveryimportantMenu = uimenu(app.Menu);
            app.HelpveryimportantMenu.MenuSelectedFcn = createCallbackFcn(app, @HELP, true);
            app.HelpveryimportantMenu.Accelerator = 'H';
            app.HelpveryimportantMenu.Text = 'Help (very important)';
            app.ContactusMenu = uimenu(app.Menu);
            app.ContactusMenu.MenuSelectedFcn = createCallbackFcn(app, @ABOUT, true);
            app.ContactusMenu.Accelerator = 'c';
            app.ContactusMenu.Text = 'Contact us';
            app.CloseallfiguresMenu = uimenu(app.Menu);
            app.CloseallfiguresMenu.MenuSelectedFcn = createCallbackFcn(app, @CloseallfiguresMenuSelected, true);
            app.CloseallfiguresMenu.Text = 'Close all figures';
            app.ExitappMenu = uimenu(app.Menu);
            app.ExitappMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitappMenuSelected, true);
            app.ExitappMenu.Text = 'Exit app';
            app.DataPanel = uipanel(app.ESDMMUIFigure);
            app.DataPanel.Title = 'Data';
            app.TimeperiodsPanel = uipanel(app.DataPanel);
            app.TimeperiodsPanel.Title = 'Time periods';
            app.TiInitialPanel = uipanel(app.TimeperiodsPanel);
            app.TiInitialPanel.Title = 'Ti: Initial';
            app.PLi = uicheckbox(app.TiInitialPanel);
            app.PLi.ValueChangedFcn = createCallbackFcn(app, @SelectDataParameters, true);
            app.PLi.Text = 'PL';
            app.LGMi = uicheckbox(app.TiInitialPanel);
            app.LGMi.ValueChangedFcn = createCallbackFcn(app, @SelectDataParameters, true);
            app.LGMi.Text = 'LGM';
            app.MHi = uicheckbox(app.TiInitialPanel);
            app.MHi.ValueChangedFcn = createCallbackFcn(app, @SelectDataParameters, true);
            app.MHi.Text = 'MH';
            app.PIi = uicheckbox(app.TiInitialPanel);
            app.PIi.ValueChangedFcn = createCallbackFcn(app, @SelectDataParameters, true);
            app.PIi.Text = 'PI';
            app.TnFinalPanel = uipanel(app.TimeperiodsPanel);
            app.TnFinalPanel.Title = 'Tn: Final';
            app.LGMn = uicheckbox(app.TnFinalPanel);
            app.LGMn.ValueChangedFcn = createCallbackFcn(app, @SelectDataParameters, true);
            app.LGMn.Text = 'LGM';
            app.MHn = uicheckbox(app.TnFinalPanel);
            app.MHn.ValueChangedFcn = createCallbackFcn(app, @SelectDataParameters, true);
            app.MHn.Text = 'MH';
            app.PIn = uicheckbox(app.TnFinalPanel);
            app.PIn.ValueChangedFcn = createCallbackFcn(app, @SelectDataParameters, true);
            app.PIn.Text = 'PI';
            app.PDn = uicheckbox(app.TnFinalPanel);
            app.PDn.ValueChangedFcn = createCallbackFcn(app, @SelectDataParameters, true);
            app.PDn.Text = 'PD';
            app.CatchmentparametersPanel = uipanel(app.DataPanel);
            app.CatchmentparametersPanel.Title = 'Catchment parameters';
            app.LRiverlengthkmPanel = uipanel(app.CatchmentparametersPanel);
            app.LRiverlengthkmPanel.Title = 'L: River length [km]';
            app.L10 = uicheckbox(app.LRiverlengthkmPanel);
            app.L10.ValueChangedFcn = createCallbackFcn(app, @SelectDataParameters, true);
            app.L10.Text = '10';
            app.L40 = uicheckbox(app.LRiverlengthkmPanel);
            app.L40.ValueChangedFcn = createCallbackFcn(app, @SelectDataParameters, true);
            app.L40.Text = '40';
            app.L80 = uicheckbox(app.LRiverlengthkmPanel);
            app.L80.ValueChangedFcn = createCallbackFcn(app, @SelectDataParameters, true);
            app.L80.Text = '80';
            app.UiInitialrockupliftmmyrPanel = uipanel(app.CatchmentparametersPanel);
            app.UiInitialrockupliftmmyrPanel.Title = 'Ui: Initial rock uplift [mm/yr]';
            app.Ui01 = uicheckbox(app.UiInitialrockupliftmmyrPanel);
            app.Ui01.ValueChangedFcn = createCallbackFcn(app, @SelectDataParameters, true);
            app.Ui01.Text = '0.1';
            app.Ui001 = uicheckbox(app.UiInitialrockupliftmmyrPanel);
            app.Ui001.ValueChangedFcn = createCallbackFcn(app, @SelectDataParameters, true);
            app.Ui001.Text = '0.01';
            app.Ui1 = uicheckbox(app.UiInitialrockupliftmmyrPanel);
            app.Ui1.ValueChangedFcn = createCallbackFcn(app, @SelectDataParameters, true);
            app.Ui1.Text = '1';
            app.Ui05 = uicheckbox(app.UiInitialrockupliftmmyrPanel);
            app.Ui05.ValueChangedFcn = createCallbackFcn(app, @SelectDataParameters, true);
            app.Ui05.Text = '0.5';
            app.KErodibilitymyr05Panel = uipanel(app.CatchmentparametersPanel);
            app.KErodibilitymyr05Panel.Title = 'K: Erodibility [(m.yr)^(-0.5)]';
            app.K55 = uicheckbox(app.KErodibilitymyr05Panel);
            app.K55.ValueChangedFcn = createCallbackFcn(app, @SelectDataParameters, true);
            app.K55.Text = '5e-5';
            app.K16 = uicheckbox(app.KErodibilitymyr05Panel);
            app.K16.ValueChangedFcn = createCallbackFcn(app, @SelectDataParameters, true);
            app.K16.Text = '1e-6';
            app.K17 = uicheckbox(app.KErodibilitymyr05Panel);
            app.K17.ValueChangedFcn = createCallbackFcn(app, @SelectDataParameters, true);
            app.K17.Text = '1e-7';
            app.DataLabel = uilabel(app.DataPanel);
            app.DataLabel.Text = 'Data';
            app.N_map = uieditfield(app.DataPanel, 'numeric');
            app.N_map.ValueDisplayFormat = '%.0f';
            app.N_map.Editable = 'off';
            app.LampRM = uilamp(app.DataPanel);
            app.LampGM = uilamp(app.DataPanel);
            app.LampGM.Enable = 'off';
            app.NumberoffiguresPanel = uipanel(app.ESDMMUIFigure);
            app.NumberoffiguresPanel.Title = '** Number of figures **';
            app.ErosionprofileLabel = uilabel(app.NumberoffiguresPanel);
            app.ErosionprofileLabel.Text = 'Erosion profile';
            app.totalprofile = uieditfield(app.NumberoffiguresPanel, 'numeric');
            app.totalprofile.ValueDisplayFormat = '%.0f';
            app.totalprofile.Editable = 'off';
            app.ErosionmapLabel = uilabel(app.NumberoffiguresPanel);
            app.ErosionmapLabel.Text = 'Erosion map';
            app.totalmap = uieditfield(app.NumberoffiguresPanel, 'numeric');
            app.totalmap.ValueDisplayFormat = '%.0f';
            app.totalmap.Editable = 'off';
            app.PrecipitationmapLabel = uilabel(app.NumberoffiguresPanel);
            app.PrecipitationmapLabel.Text = 'Precipitation map';
            app.totalprecipitation = uieditfield(app.NumberoffiguresPanel, 'numeric');
            app.totalprecipitation.ValueDisplayFormat = '%.0f';
            app.totalprecipitation.Editable = 'off';
            app.PlothistogramLabel = uilabel(app.NumberoffiguresPanel);
            app.PlothistogramLabel.Text = 'Plot histogram';
            app.totalhistogarm = uieditfield(app.NumberoffiguresPanel, 'numeric');
            app.totalhistogarm.ValueDisplayFormat = '%.0f';
            app.totalhistogarm.Editable = 'off';
            app.TemperaturemapLabel = uilabel(app.NumberoffiguresPanel);
            app.TemperaturemapLabel.Text = 'Temperature map';
            app.totaltemperature = uieditfield(app.NumberoffiguresPanel, 'numeric');
            app.totaltemperature.ValueDisplayFormat = '%.0f';
            app.totaltemperature.Editable = 'off';
            app.ErosionMapPanel = uipanel(app.ESDMMUIFigure);
            app.ErosionMapPanel.Title = 'Erosion Map';
            app.MapcategoriesPanel = uipanel(app.ErosionMapPanel);
            app.MapcategoriesPanel.Title = 'Map categories';
            app.RT = uicheckbox(app.MapcategoriesPanel);
            app.RT.ValueChangedFcn = createCallbackFcn(app, @MapCategoryParameters, true);
            app.RT.Enable = 'off';
            app.RT.Text = 'RT: Response time [kyr]';
            app.RE = uicheckbox(app.MapcategoriesPanel);
            app.RE.ValueChangedFcn = createCallbackFcn(app, @MapCategoryParameters, true);
            app.RE.Enable = 'off';
            app.RE.Text = 'RE: Mean catchment River Erosion rate [mm/yr]';
            app.RE_Ui = uicheckbox(app.MapcategoriesPanel);
            app.RE_Ui.ValueChangedFcn = createCallbackFcn(app, @MapCategoryParameters, true);
            app.RE_Ui.Enable = 'off';
            app.RE_Ui.Text = 'RE(Ui): Change in RE relative to Ui [%]';
            app.RE_Peak = uicheckbox(app.MapcategoriesPanel);
            app.RE_Peak.ValueChangedFcn = createCallbackFcn(app, @MapCategoryParameters, true);
            app.RE_Peak.Enable = 'off';
            app.RE_Peak.Text = 'RE(Peak): Change in RE relative to Peak [%]';
            app.CE_Peak = uicheckbox(app.MapcategoriesPanel);
            app.CE_Peak.ValueChangedFcn = createCallbackFcn(app, @MapCategoryParameters, true);
            app.CE_Peak.Enable = 'off';
            app.CE_Peak.Text = 'CE(Peak): Change in CE relative to Peak [%]';
            app.CE_Ui = uicheckbox(app.MapcategoriesPanel);
            app.CE_Ui.ValueChangedFcn = createCallbackFcn(app, @MapCategoryParameters, true);
            app.CE_Ui.Enable = 'off';
            app.CE_Ui.Text = 'CE(Ui): Change in CE relative to Ui [%]';
            app.KPT = uicheckbox(app.MapcategoriesPanel);
            app.KPT.ValueChangedFcn = createCallbackFcn(app, @MapCategoryParameters, true);
            app.KPT.Enable = 'off';
            app.KPT.Text = 'KPT: Lateral shift in knickpoint position [%]';
            app.CE = uicheckbox(app.MapcategoriesPanel);
            app.CE.ValueChangedFcn = createCallbackFcn(app, @MapCategoryParameters, true);
            app.CE.Enable = 'off';
            app.CE.Text = 'CE: Mean catchment Cosmogenic Erosion rate [mm/yr]';
            app.atPanel = uipanel(app.ErosionMapPanel);
            app.atPanel.Title = '@ What point ???';
            app.atTT = uicheckbox(app.atPanel);
            app.atTT.ValueChangedFcn = createCallbackFcn(app, @MapAtParameters, true);
            app.atTT.Enable = 'off';
            app.atTT.Text = '@Tn';
            app.atEnd = uicheckbox(app.atPanel);
            app.atEnd.ValueChangedFcn = createCallbackFcn(app, @MapAtParameters, true);
            app.atEnd.Enable = 'off';
            app.atEnd.Text = '@N.S.S.';
            app.atREUi50 = uicheckbox(app.atPanel);
            app.atREUi50.ValueChangedFcn = createCallbackFcn(app, @MapAtParameters, true);
            app.atREUi50.Enable = 'off';
            app.atREUi50.Text = '@50% RE(Ui)';
            app.atREUi40 = uicheckbox(app.atPanel);
            app.atREUi40.ValueChangedFcn = createCallbackFcn(app, @MapAtParameters, true);
            app.atREUi40.Enable = 'off';
            app.atREUi40.Text = '@40% RE(Ui)';
            app.atREUi30 = uicheckbox(app.atPanel);
            app.atREUi30.ValueChangedFcn = createCallbackFcn(app, @MapAtParameters, true);
            app.atREUi30.Enable = 'off';
            app.atREUi30.Text = '@30% RE(Ui)';
            app.atREUi20 = uicheckbox(app.atPanel);
            app.atREUi20.ValueChangedFcn = createCallbackFcn(app, @MapAtParameters, true);
            app.atREUi20.Enable = 'off';
            app.atREUi20.Text = '@20% RE(Ui)';
            app.atREUi10 = uicheckbox(app.atPanel);
            app.atREUi10.ValueChangedFcn = createCallbackFcn(app, @MapAtParameters, true);
            app.atREUi10.Enable = 'off';
            app.atREUi10.Text = '@10% RE(Ui)';
            app.atREPeak10 = uicheckbox(app.atPanel);
            app.atREPeak10.ValueChangedFcn = createCallbackFcn(app, @MapAtParameters, true);
            app.atREPeak10.Enable = 'off';
            app.atREPeak10.Text = '@10% RE(Peak)';
            app.atREPeak20 = uicheckbox(app.atPanel);
            app.atREPeak20.ValueChangedFcn = createCallbackFcn(app, @MapAtParameters, true);
            app.atREPeak20.Enable = 'off';
            app.atREPeak20.Text = '@20% RE(Peak)';
            app.atREPeak30 = uicheckbox(app.atPanel);
            app.atREPeak30.ValueChangedFcn = createCallbackFcn(app, @MapAtParameters, true);
            app.atREPeak30.Enable = 'off';
            app.atREPeak30.Text = '@30% RE(Peak)';
            app.atREPeak40 = uicheckbox(app.atPanel);
            app.atREPeak40.ValueChangedFcn = createCallbackFcn(app, @MapAtParameters, true);
            app.atREPeak40.Enable = 'off';
            app.atREPeak40.Text = '@40% RE(Peak)';
            app.atREPeak50 = uicheckbox(app.atPanel);
            app.atREPeak50.ValueChangedFcn = createCallbackFcn(app, @MapAtParameters, true);
            app.atREPeak50.Enable = 'off';
            app.atREPeak50.Text = '@50% RE(Peak)';
            app.atPeakRE = uicheckbox(app.atPanel);
            app.atPeakRE.ValueChangedFcn = createCallbackFcn(app, @MapAtParameters, true);
            app.atPeakRE.Enable = 'off';
            app.atPeakRE.Text = '@Peak RE';
            app.atCEUi10 = uicheckbox(app.atPanel);
            app.atCEUi10.ValueChangedFcn = createCallbackFcn(app, @MapAtParameters, true);
            app.atCEUi10.Enable = 'off';
            app.atCEUi10.Text = '@10% CE(Ui)';
            app.atCEUi20 = uicheckbox(app.atPanel);
            app.atCEUi20.ValueChangedFcn = createCallbackFcn(app, @MapAtParameters, true);
            app.atCEUi20.Enable = 'off';
            app.atCEUi20.Text = '@20% CE(Ui)';
            app.atCEUi30 = uicheckbox(app.atPanel);
            app.atCEUi30.ValueChangedFcn = createCallbackFcn(app, @MapAtParameters, true);
            app.atCEUi30.Enable = 'off';
            app.atCEUi30.Text = '@30% CE(Ui)';
            app.atCEUi40 = uicheckbox(app.atPanel);
            app.atCEUi40.ValueChangedFcn = createCallbackFcn(app, @MapAtParameters, true);
            app.atCEUi40.Enable = 'off';
            app.atCEUi40.Text = '@40% CE(Ui)';
            app.atCEUi50 = uicheckbox(app.atPanel);
            app.atCEUi50.ValueChangedFcn = createCallbackFcn(app, @MapAtParameters, true);
            app.atCEUi50.Enable = 'off';
            app.atCEUi50.Text = '@50% CE(Ui)';
            app.atPeakCE = uicheckbox(app.atPanel);
            app.atPeakCE.ValueChangedFcn = createCallbackFcn(app, @MapAtParameters, true);
            app.atPeakCE.Enable = 'off';
            app.atPeakCE.Text = '@Peak CE';
            app.atCEPeak10 = uicheckbox(app.atPanel);
            app.atCEPeak10.ValueChangedFcn = createCallbackFcn(app, @MapAtParameters, true);
            app.atCEPeak10.Enable = 'off';
            app.atCEPeak10.Text = '@10% CE(Peak)';
            app.atCEPeak20 = uicheckbox(app.atPanel);
            app.atCEPeak20.ValueChangedFcn = createCallbackFcn(app, @MapAtParameters, true);
            app.atCEPeak20.Enable = 'off';
            app.atCEPeak20.Text = '@20% CE(Peak)';
            app.atCEPeak30 = uicheckbox(app.atPanel);
            app.atCEPeak30.ValueChangedFcn = createCallbackFcn(app, @MapAtParameters, true);
            app.atCEPeak30.Enable = 'off';
            app.atCEPeak30.Text = '@30% CE(Peak)';
            app.atCEPeak40 = uicheckbox(app.atPanel);
            app.atCEPeak40.ValueChangedFcn = createCallbackFcn(app, @MapAtParameters, true);
            app.atCEPeak40.Enable = 'off';
            app.atCEPeak40.Text = '@40% CE(Peak)';
            app.atCEPeak50 = uicheckbox(app.atPanel);
            app.atCEPeak50.ValueChangedFcn = createCallbackFcn(app, @MapAtParameters, true);
            app.atCEPeak50.Enable = 'off';
            app.atCEPeak50.Text = '@50% CE(Peak)';
            app.Bpeak = uicheckbox(app.atPanel);
            app.Bpeak.ValueChangedFcn = createCallbackFcn(app, @MapAtParameters, true);
            app.Bpeak.Enable = 'off';
            app.Bpeak.Text = 'Before Peak';
            app.Apeak = uicheckbox(app.atPanel);
            app.Apeak.ValueChangedFcn = createCallbackFcn(app, @MapAtParameters, true);
            app.Apeak.Enable = 'off';
            app.Apeak.Text = 'After Peak';
            app.OptionsPanel = uipanel(app.ESDMMUIFigure);
            app.OptionsPanel.Title = '** Options **';
            app.border = uicheckbox(app.OptionsPanel);
            app.border.ValueChangedFcn = createCallbackFcn(app, @MapExtraParameters, true);
            app.border.Text = 'Country borders';
            app.border.Value = true;
            app.stat = uicheckbox(app.OptionsPanel);
            app.stat.ValueChangedFcn = createCallbackFcn(app, @MapExtraParameters, true);
            app.stat.Text = 'Statistic values';
            app.stat.Value = true;
            app.sample = uicheckbox(app.OptionsPanel);
            app.sample.ValueChangedFcn = createCallbackFcn(app, @MapExtraParameters, true);
            app.sample.Text = 'Cosmo sample';
            app.ice = uicheckbox(app.OptionsPanel);
            app.ice.ValueChangedFcn = createCallbackFcn(app, @MapExtraParameters, true);
            app.ice.Text = 'LGM ice cover';
            app.PlotareaPanel = uipanel(app.OptionsPanel);
            app.PlotareaPanel.Title = 'Plot area';
            app.TextArea = uitextarea(app.PlotareaPanel);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Bottom left'};
            app.TextArea_2 = uitextarea(app.PlotareaPanel);
            app.TextArea_2.Editable = 'off';
            app.TextArea_2.Value = {'Top right'};
            app.LatEditFieldLabel = uilabel(app.PlotareaPanel);
            app.LatEditFieldLabel.Text = 'Lat';
            app.LBlat = uieditfield(app.PlotareaPanel, 'numeric');
            app.LBlat.Limits = [-60 74.99];
            app.LBlat.ValueDisplayFormat = '%.2f';
            app.LBlat.ValueChangedFcn = createCallbackFcn(app, @MapExtraParameters, true);
            app.LBlat.Value = -60;
            app.LonLabel = uilabel(app.PlotareaPanel);
            app.LonLabel.Text = 'Lon';
            app.LBlong = uieditfield(app.PlotareaPanel, 'numeric');
            app.LBlong.Limits = [-180 179.99];
            app.LBlong.ValueDisplayFormat = '%.2f';
            app.LBlong.ValueChangedFcn = createCallbackFcn(app, @MapExtraParameters, true);
            app.LBlong.Value = -180;
            app.LonLabel_2 = uilabel(app.PlotareaPanel);
            app.LonLabel_2.Text = 'Lon';
            app.TRlong = uieditfield(app.PlotareaPanel, 'numeric');
            app.TRlong.Limits = [-179.99 180];
            app.TRlong.ValueDisplayFormat = '%.2f';
            app.TRlong.ValueChangedFcn = createCallbackFcn(app, @MapExtraParameters, true);
            app.TRlong.Value = 180;
            app.LatEditField_2Label = uilabel(app.PlotareaPanel);
            app.LatEditField_2Label.Text = 'Lat';
            app.TRlat = uieditfield(app.PlotareaPanel, 'numeric');
            app.TRlat.Limits = [-59.99 75];
            app.TRlat.ValueDisplayFormat = '%.2f';
            app.TRlat.ValueChangedFcn = createCallbackFcn(app, @MapExtraParameters, true);
            app.TRlat.Value = 75;
            app.WorldCo = uibutton(app.PlotareaPanel, 'push');
            app.WorldCo.ButtonPushedFcn = createCallbackFcn(app, @WorldCoButtonPushed, true);
            app.WorldCo.Text = 'World';
            app.WorldCo_5 = uibutton(app.PlotareaPanel, 'push');
            app.WorldCo_5.ButtonPushedFcn = createCallbackFcn(app, @WorldCo_5ButtonPushed, true);
            app.WorldCo_5.Text = 'EarthShape';
            app.SouthAmericaCo_2 = uibutton(app.PlotareaPanel, 'push');
            app.SouthAmericaCo_2.ButtonPushedFcn = createCallbackFcn(app, @SouthAmericaCo_2ButtonPushed, true);
            app.SouthAmericaCo_2.Text = 'North America';
            app.WorldCo_3 = uibutton(app.PlotareaPanel, 'push');
            app.WorldCo_3.ButtonPushedFcn = createCallbackFcn(app, @WorldCo_3ButtonPushed, true);
            app.WorldCo_3.Text = 'Australia';
            app.SouthAmericaCo_3 = uibutton(app.PlotareaPanel, 'push');
            app.SouthAmericaCo_3.ButtonPushedFcn = createCallbackFcn(app, @SouthAmericaCo_3ButtonPushed, true);
            app.SouthAmericaCo_3.Text = 'Europe';
            app.SouthAmericaCo = uibutton(app.PlotareaPanel, 'push');
            app.SouthAmericaCo.ButtonPushedFcn = createCallbackFcn(app, @SouthAmericaCoButtonPushed, true);
            app.SouthAmericaCo.Text = 'South America';
            app.WorldCo_4 = uibutton(app.PlotareaPanel, 'push');
            app.WorldCo_4.ButtonPushedFcn = createCallbackFcn(app, @WorldCo_4ButtonPushed, true);
            app.WorldCo_4.Text = 'Africa';
            app.WorldCo_6 = uibutton(app.PlotareaPanel, 'push');
            app.WorldCo_6.ButtonPushedFcn = createCallbackFcn(app, @WorldCo_6ButtonPushed, true);
            app.WorldCo_6.Text = 'USA';
            app.SouthAmericaCo_4 = uibutton(app.PlotareaPanel, 'push');
            app.SouthAmericaCo_4.ButtonPushedFcn = createCallbackFcn(app, @SouthAmericaCo_4ButtonPushed, true);
            app.SouthAmericaCo_4.Text = 'Asia';
            app.WorldCo_2 = uibutton(app.PlotareaPanel, 'push');
            app.WorldCo_2.ButtonPushedFcn = createCallbackFcn(app, @WorldCo_2ButtonPushed, true);
            app.WorldCo_2.Text = 'Germany';
            app.ColorbartypeButtonGroup = uibuttongroup(app.OptionsPanel);
            app.ColorbartypeButtonGroup.Title = 'Colorbar type';
            app.CT_Fixed = uiradiobutton(app.ColorbartypeButtonGroup);
            app.CT_Fixed.Text = 'Fixed';
            app.CT_Fixed.Value = true;
            app.CT_EqDstnc = uiradiobutton(app.ColorbartypeButtonGroup);
            app.CT_EqDstnc.Text = 'Eq. Dstnc';
            app.CT_Eqdstrbtn = uiradiobutton(app.ColorbartypeButtonGroup);
            app.CT_Eqdstrbtn.Text = 'Eq. dstrbtn';
            app.ErosionProfilePanel = uipanel(app.ESDMMUIFigure);
            app.ErosionProfilePanel.Title = 'Erosion Profile';
            app.SinglepointisNofEquatorEofPrimeMeridianPanel = uipanel(app.ErosionProfilePanel);
            app.SinglepointisNofEquatorEofPrimeMeridianPanel.Title = 'Single point (+ is N of Equator & E of Prime Meridian)';
            app.Longitude180to180Label = uilabel(app.SinglepointisNofEquatorEofPrimeMeridianPanel);
            app.Longitude180to180Label.Text = 'Longitude (-180° to 180°)';
            app.longinput = uieditfield(app.SinglepointisNofEquatorEofPrimeMeridianPanel, 'numeric');
            app.longinput.Limits = [-180 180];
            app.longinput.ValueDisplayFormat = '%.2f';
            app.longinput.ValueChangedFcn = createCallbackFcn(app, @ProfileParameters, true);
            app.longinput.Enable = 'off';
            app.Latitude60to75Label = uilabel(app.SinglepointisNofEquatorEofPrimeMeridianPanel);
            app.Latitude60to75Label.Text = 'Latitude (-60° to 75°)';
            app.latinput = uieditfield(app.SinglepointisNofEquatorEofPrimeMeridianPanel, 'numeric');
            app.latinput.Limits = [-60 75];
            app.latinput.ValueDisplayFormat = '%.2f';
            app.latinput.ValueChangedFcn = createCallbackFcn(app, @ProfileParameters, true);
            app.latinput.Enable = 'off';
            app.Allovertheworld26119availablepointsPanel = uipanel(app.ErosionProfilePanel);
            app.Allovertheworld26119availablepointsPanel.Title = 'All over the world (26119 available points)';
            app.NumberofrandompointsEditField_2Label = uilabel(app.Allovertheworld26119availablepointsPanel);
            app.NumberofrandompointsEditField_2Label.Text = 'Number of random points';
            app.numrandall = uieditfield(app.Allovertheworld26119availablepointsPanel, 'numeric');
            app.numrandall.Limits = [1 26119];
            app.numrandall.ValueChangedFcn = createCallbackFcn(app, @ProfileParameters, true);
            app.numrandall.Enable = 'off';
            app.numrandall.Value = 1;
            app.randall = uicheckbox(app.Allovertheworld26119availablepointsPanel);
            app.randall.ValueChangedFcn = createCallbackFcn(app, @ProfileParameters, true);
            app.randall.Enable = 'off';
            app.randall.Text = 'Enable';
            app.Cosmogenicsamplepoints3207samplepointsPanel = uipanel(app.ErosionProfilePanel);
            app.Cosmogenicsamplepoints3207samplepointsPanel.Title = 'Cosmogenic sample points (3207 sample points)';
            app.NumberofrandompointsEditField_3Label = uilabel(app.Cosmogenicsamplepoints3207samplepointsPanel);
            app.NumberofrandompointsEditField_3Label.Text = 'Number of random points';
            app.numrandsample = uieditfield(app.Cosmogenicsamplepoints3207samplepointsPanel, 'numeric');
            app.numrandsample.Limits = [0 3207];
            app.numrandsample.ValueChangedFcn = createCallbackFcn(app, @ProfileParameters, true);
            app.numrandsample.Enable = 'off';
            app.numrandsample.Value = 1;
            app.randsample = uicheckbox(app.Cosmogenicsamplepoints3207samplepointsPanel);
            app.randsample.ValueChangedFcn = createCallbackFcn(app, @ProfileParameters, true);
            app.randsample.Enable = 'off';
            app.randsample.Text = 'Enable';
            app.BasedonStatisticthepeakoftherivererosionratePanel = uipanel(app.ErosionProfilePanel);
            app.BasedonStatisticthepeakoftherivererosionratePanel.Title = 'Based on Statistic @ the peak of the river erosion rate';
            app.maxpos = uicheckbox(app.BasedonStatisticthepeakoftherivererosionratePanel);
            app.maxpos.ValueChangedFcn = createCallbackFcn(app, @ProfileParameters, true);
            app.maxpos.Enable = 'off';
            app.maxpos.Text = '@Max';
            app.medpos = uicheckbox(app.BasedonStatisticthepeakoftherivererosionratePanel);
            app.medpos.ValueChangedFcn = createCallbackFcn(app, @ProfileParameters, true);
            app.medpos.Enable = 'off';
            app.medpos.Text = '@Median(+)';
            app.meanpos = uicheckbox(app.BasedonStatisticthepeakoftherivererosionratePanel);
            app.meanpos.ValueChangedFcn = createCallbackFcn(app, @ProfileParameters, true);
            app.meanpos.Enable = 'off';
            app.meanpos.Text = '@Mean(+)';
            app.modepos = uicheckbox(app.BasedonStatisticthepeakoftherivererosionratePanel);
            app.modepos.ValueChangedFcn = createCallbackFcn(app, @ProfileParameters, true);
            app.modepos.Enable = 'off';
            app.modepos.Text = '@Mode(+)';
            app.minneg = uicheckbox(app.BasedonStatisticthepeakoftherivererosionratePanel);
            app.minneg.ValueChangedFcn = createCallbackFcn(app, @ProfileParameters, true);
            app.minneg.Enable = 'off';
            app.minneg.Text = '@Min';
            app.medov = uicheckbox(app.BasedonStatisticthepeakoftherivererosionratePanel);
            app.medov.ValueChangedFcn = createCallbackFcn(app, @ProfileParameters, true);
            app.medov.Enable = 'off';
            app.medov.Text = '@Median(all)';
            app.meanov = uicheckbox(app.BasedonStatisticthepeakoftherivererosionratePanel);
            app.meanov.ValueChangedFcn = createCallbackFcn(app, @ProfileParameters, true);
            app.meanov.Enable = 'off';
            app.meanov.Text = '@Mean(all)';
            app.modetot = uicheckbox(app.BasedonStatisticthepeakoftherivererosionratePanel);
            app.modetot.ValueChangedFcn = createCallbackFcn(app, @ProfileParameters, true);
            app.modetot.Enable = 'off';
            app.modetot.Text = '@Mode(all)';
            app.medneg = uicheckbox(app.BasedonStatisticthepeakoftherivererosionratePanel);
            app.medneg.ValueChangedFcn = createCallbackFcn(app, @ProfileParameters, true);
            app.medneg.Enable = 'off';
            app.medneg.Text = '@Median(-)';
            app.meanneg = uicheckbox(app.BasedonStatisticthepeakoftherivererosionratePanel);
            app.meanneg.ValueChangedFcn = createCallbackFcn(app, @ProfileParameters, true);
            app.meanneg.Enable = 'off';
            app.meanneg.Text = '@Mean(-)';
            app.modeneg = uicheckbox(app.BasedonStatisticthepeakoftherivererosionratePanel);
            app.modeneg.ValueChangedFcn = createCallbackFcn(app, @ProfileParameters, true);
            app.modeneg.Enable = 'off';
            app.modeneg.Text = '@Mode(-)';
            app.Statistic = uicheckbox(app.BasedonStatisticthepeakoftherivererosionratePanel);
            app.Statistic.ValueChangedFcn = createCallbackFcn(app, @ProfileParameters, true);
            app.Statistic.Enable = 'off';
            app.Statistic.Text = 'Enable';
            app.coordinate = uicheckbox(app.ErosionProfilePanel);
            app.coordinate.ValueChangedFcn = createCallbackFcn(app, @ProfileParameters, true);
            app.coordinate.Enable = 'off';
            app.coordinate.Text = 'Enable';
            app.NoneErosionMapmeanannualPanel = uipanel(app.ESDMMUIFigure);
            app.NoneErosionMapmeanannualPanel.Title = 'None Erosion Map (mean annual)';
            app.PLprec = uicheckbox(app.NoneErosionMapmeanannualPanel);
            app.PLprec.ValueChangedFcn = createCallbackFcn(app, @NoneErosionMaps, true);
            app.PLprec.Enable = 'off';
            app.PLprec.Text = 'PL';
            app.LGMprec = uicheckbox(app.NoneErosionMapmeanannualPanel);
            app.LGMprec.ValueChangedFcn = createCallbackFcn(app, @NoneErosionMaps, true);
            app.LGMprec.Enable = 'off';
            app.LGMprec.Text = 'LGM';
            app.MHprec = uicheckbox(app.NoneErosionMapmeanannualPanel);
            app.MHprec.ValueChangedFcn = createCallbackFcn(app, @NoneErosionMaps, true);
            app.MHprec.Enable = 'off';
            app.MHprec.Text = 'MH';
            app.PIprec = uicheckbox(app.NoneErosionMapmeanannualPanel);
            app.PIprec.ValueChangedFcn = createCallbackFcn(app, @NoneErosionMaps, true);
            app.PIprec.Enable = 'off';
            app.PIprec.Text = 'PI';
            app.PDprec = uicheckbox(app.NoneErosionMapmeanannualPanel);
            app.PDprec.ValueChangedFcn = createCallbackFcn(app, @NoneErosionMaps, true);
            app.PDprec.Enable = 'off';
            app.PDprec.Text = 'PD';
            app.PLLGMdif = uicheckbox(app.NoneErosionMapmeanannualPanel);
            app.PLLGMdif.ValueChangedFcn = createCallbackFcn(app, @NoneErosionMaps, true);
            app.PLLGMdif.Enable = 'off';
            app.PLLGMdif.Text = 'PL-LGM';
            app.PLMHdif = uicheckbox(app.NoneErosionMapmeanannualPanel);
            app.PLMHdif.ValueChangedFcn = createCallbackFcn(app, @NoneErosionMaps, true);
            app.PLMHdif.Enable = 'off';
            app.PLMHdif.Text = 'PL-MH';
            app.PLPIdif = uicheckbox(app.NoneErosionMapmeanannualPanel);
            app.PLPIdif.ValueChangedFcn = createCallbackFcn(app, @NoneErosionMaps, true);
            app.PLPIdif.Enable = 'off';
            app.PLPIdif.Text = 'PL-PI';
            app.PLPDdif = uicheckbox(app.NoneErosionMapmeanannualPanel);
            app.PLPDdif.ValueChangedFcn = createCallbackFcn(app, @NoneErosionMaps, true);
            app.PLPDdif.Enable = 'off';
            app.PLPDdif.Text = 'PL-PD';
            app.LGMMHdif = uicheckbox(app.NoneErosionMapmeanannualPanel);
            app.LGMMHdif.ValueChangedFcn = createCallbackFcn(app, @NoneErosionMaps, true);
            app.LGMMHdif.Enable = 'off';
            app.LGMMHdif.Text = 'LGM-MH';
            app.MHPIdif = uicheckbox(app.NoneErosionMapmeanannualPanel);
            app.MHPIdif.ValueChangedFcn = createCallbackFcn(app, @NoneErosionMaps, true);
            app.MHPIdif.Enable = 'off';
            app.MHPIdif.Text = 'MH-PI';
            app.LGMPDdif = uicheckbox(app.NoneErosionMapmeanannualPanel);
            app.LGMPDdif.ValueChangedFcn = createCallbackFcn(app, @NoneErosionMaps, true);
            app.LGMPDdif.Enable = 'off';
            app.LGMPDdif.Text = 'LGM-PD';
            app.LGMPIdif = uicheckbox(app.NoneErosionMapmeanannualPanel);
            app.LGMPIdif.ValueChangedFcn = createCallbackFcn(app, @NoneErosionMaps, true);
            app.LGMPIdif.Enable = 'off';
            app.LGMPIdif.Text = 'LGM-PI';
            app.MHPDdif = uicheckbox(app.NoneErosionMapmeanannualPanel);
            app.MHPDdif.ValueChangedFcn = createCallbackFcn(app, @NoneErosionMaps, true);
            app.MHPDdif.Enable = 'off';
            app.MHPDdif.Text = 'MH-PD';
            app.PIPDdif = uicheckbox(app.NoneErosionMapmeanannualPanel);
            app.PIPDdif.ValueChangedFcn = createCallbackFcn(app, @NoneErosionMaps, true);
            app.PIPDdif.Enable = 'off';
            app.PIPDdif.Text = 'PI-PD';
            app.onefig = uicheckbox(app.NoneErosionMapmeanannualPanel);
            app.onefig.ValueChangedFcn = createCallbackFcn(app, @NoneErosionMaps, true);
            app.onefig.Text = 'Together';
            app.sepfig = uicheckbox(app.NoneErosionMapmeanannualPanel);
            app.sepfig.ValueChangedFcn = createCallbackFcn(app, @NoneErosionMaps, true);
            app.sepfig.Text = 'Individual';
            app.PrecipitationMap = uicheckbox(app.NoneErosionMapmeanannualPanel);
            app.PrecipitationMap.ValueChangedFcn = createCallbackFcn(app, @NoneErosionMaps, true);
            app.PrecipitationMap.Text = 'Precipitation';
            app.TemperatureMap = uicheckbox(app.NoneErosionMapmeanannualPanel);
            app.TemperatureMap.ValueChangedFcn = createCallbackFcn(app, @NoneErosionMaps, true);
            app.TemperatureMap.Text = 'Temperature';
            app.RealtimemapselectedpointsandareaPanel = uipanel(app.ESDMMUIFigure);
            app.RealtimemapselectedpointsandareaPanel.Title = 'Real-time map (selected points and area)';
            app.UIAxes = uiaxes(app.RealtimemapselectedpointsandareaPanel);
            app.UIAxes.XLim = [-180 180];
            app.UIAxes.YLim = [-90 90];
            app.UIAxes.XTick = [-180 -150 -120 -90 -60 -30 0 30 60 90 120 150 180];
            app.UIAxes.XTickLabel = {'-180'; '-150'; '-120'; '-90'; '-60'; '-30'; '0'; '30'; '60'; '90'; '120'; '150'; '180'};
            app.UIAxes.YTick = [-90 -60 -30 0 30 60 90];
            app.UIAxes.YTickLabel = {'-90'; '-60'; '-30'; '0'; '30'; '60'; '90'};
            app.runpanel = uipanel(app.ESDMMUIFigure);
            app.runpanel.Title = 'SAVE';
            app.SavefolderButtonGroup = uibuttongroup(app.runpanel);
            app.SavefolderButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @RUNpanel, true);
            app.SavefolderButtonGroup.Title = 'Save folder';
            app.AlltogetherButton = uiradiobutton(app.SavefolderButtonGroup);
            app.AlltogetherButton.Enable = 'off';
            app.AlltogetherButton.Text = 'All together';
            app.AlltogetherButton.Value = true;
            app.CategorizedButton = uiradiobutton(app.SavefolderButtonGroup);
            app.CategorizedButton.Enable = 'off';
            app.CategorizedButton.Text = 'Categorized';
            app.PLOTCheckBox = uicheckbox(app.runpanel);
            app.PLOTCheckBox.ValueChangedFcn = createCallbackFcn(app, @RUNpanel, true);
            app.PLOTCheckBox.Text = 'PLOT';
            app.PLOTCheckBox.Value = true;
            app.SAVECheckBox = uicheckbox(app.runpanel);
            app.SAVECheckBox.ValueChangedFcn = createCallbackFcn(app, @RUNpanel, true);
            app.SAVECheckBox.Text = 'SAVE';
            app.SaveformatButtonGroup = uibuttongroup(app.runpanel);
            app.SaveformatButtonGroup.Title = 'Save format';
            app.JPG = uicheckbox(app.SaveformatButtonGroup);
            app.JPG.ValueChangedFcn = createCallbackFcn(app, @RUNpanel, true);
            app.JPG.Enable = 'off';
            app.JPG.Text = '*.jpg';
            app.BMP = uicheckbox(app.SaveformatButtonGroup);
            app.BMP.ValueChangedFcn = createCallbackFcn(app, @RUNpanel, true);
            app.BMP.Enable = 'off';
            app.BMP.Text = '*.bmp';
            app.PDF = uicheckbox(app.SaveformatButtonGroup);
            app.PDF.ValueChangedFcn = createCallbackFcn(app, @RUNpanel, true);
            app.PDF.Enable = 'off';
            app.PDF.Text = '*.pdf';
            app.SVG = uicheckbox(app.SaveformatButtonGroup);
            app.SVG.ValueChangedFcn = createCallbackFcn(app, @RUNpanel, true);
            app.SVG.Enable = 'off';
            app.SVG.Text = '*.svg';
            app.PlotHistogramPanel = uipanel(app.ESDMMUIFigure);
            app.PlotHistogramPanel.Title = 'Plot Histogram';
            app.hist1 = uicheckbox(app.PlotHistogramPanel);
            app.hist1.ValueChangedFcn = createCallbackFcn(app, @Histogram_panel, true);
            app.hist1.Enable = 'off';
            app.hist1.Text = 'Plot ratio of: RT@PEAK of CE / observed RT of CE';
            app.hist2 = uicheckbox(app.PlotHistogramPanel);
            app.hist2.ValueChangedFcn = createCallbackFcn(app, @Histogram_panel, true);
            app.hist2.Enable = 'off';
            app.hist2.Text = 'Plot ratio of: Peak of RE / Peak of CE ';
            app.ButtonGroup = uibuttongroup(app.ESDMMUIFigure);
            app.loadingbar = uigauge(app.ButtonGroup, 'linear');
            app.loadingbar.MajorTicks = [0 10 20 30 40 50 60 70 80 90 100];
            app.loadingbar.MajorTickLabels = {'', '10%', '20%', '30%', '40%', '50%', '60%', '70%', '80%', '90%', 'Done'};
            app.loadingbar.MinorTicks = [];
            app.loadinglabel = uilabel(app.ButtonGroup);
            app.loadinglabel.Text = '....';
            app.RUN = uibutton(app.ButtonGroup, 'push');
            app.RUN.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUN.Enable = 'off';
            app.RUN.Text = 'RUN';
            app.LampRUN = uilamp(app.ButtonGroup);
            app.LampRUN.Enable = 'off';
            app.Cancel = uiswitch(app.ButtonGroup, 'rocker');
            app.Cancel.Items = {'Stop', ''};
            app.Cancel.ValueChangedFcn = createCallbackFcn(app, @CancelValueChanged, true);
            app.Cancel.Value = '';
            app.LampSTOP = uilamp(app.ButtonGroup);
            app.LampSTOP.Enable = 'off';
            % Show the figure after all components are created
            app.ESDMMUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ESD

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ESDMMUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ESDMMUIFigure)
        end
    end
end